classdef BatchJTtime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        LoadingFilesTab            matlab.ui.container.Tab
        Main_GridLayout            matlab.ui.container.GridLayout
        Save_GridLayout            matlab.ui.container.GridLayout
        LoadChosenFilesButton      matlab.ui.control.Button
        RunBatch_Button            matlab.ui.control.Button
        ProgressReadout            matlab.ui.control.TextArea
        UpdatesText_Label          matlab.ui.control.Label
        ProcesMethod_GridLayout    matlab.ui.container.GridLayout
        RunMClust_CheckBox         matlab.ui.control.CheckBox
        IncludeMClust_Label        matlab.ui.control.Label
        MClustCurrentFile_Label    matlab.ui.control.Label
        SaveChoice_Button          matlab.ui.control.Button
        FileChoice_DropDown        matlab.ui.control.DropDown
        fileProcess_Label          matlab.ui.control.Label
        AllFiles_CheckBox          matlab.ui.control.CheckBox
        LoadMethodAll_CheckBox     matlab.ui.control.CheckBox
        AllFiles_Label             matlab.ui.control.Label
        ProcessType_DropDown       matlab.ui.control.DropDown
        Savemethod_Label           matlab.ui.control.Label
        LoadingProcess_DropDown    matlab.ui.control.DropDown
        Processmethod_Label        matlab.ui.control.Label
        FileChoice_GridLayout      matlab.ui.container.GridLayout
        LoadedFiles_GridLayout     matlab.ui.container.GridLayout
        Filesloaded_Label          matlab.ui.control.Label
        FileList_TextArea          matlab.ui.control.TextArea
        LoadingFile_GridLayout     matlab.ui.container.GridLayout
        OdorandTrial_GridLayout    matlab.ui.container.GridLayout
        trial_amt                  matlab.ui.control.NumericEditField
        dt_amt                     matlab.ui.control.NumericEditField
        Trial_Label                matlab.ui.control.Label
        dt_Label                   matlab.ui.control.Label
        DataLoc_Button             matlab.ui.control.Button
        DataLoc_TextArea           matlab.ui.control.TextArea
        FolderLoc_Label            matlab.ui.control.Label
        LoadingMethod_ButtonGroup  matlab.ui.container.ButtonGroup
        ExcelChoice_Button         matlab.ui.control.RadioButton
        ListChoice_Button          matlab.ui.control.RadioButton
        FolderChoice_Button        matlab.ui.control.RadioButton
        Method_Label               matlab.ui.control.Label
        SelectChannelsTab          matlab.ui.container.Tab
        Channels_GridLayout        matlab.ui.container.GridLayout
    end

    
    properties (Access = public)
        FileLoc % Location of all files (cell array [Loc,Name])
        ProcesType % Process type for each file (num array)
        MCinclud % Include MClust (logic array)
        FileProcInfo % Information on all files being processed
        Flags % flags used in code processing
        drta_Data % data loaded from the file
        OutputText 
        drta_Main
        AnalogFigures
        data_files % staging place for all the files
        drta_Save
    end

    methods (Access = public)
        BatchJTtimeStartUp(app) % startup function
        LoadingTypeUsed(app,event) % loading type radio button choice
        LoadData(app) % data loading function
        RunningBachProcess(app,FileNum) % running batch process
        Batch_open_rhd(app,FileNum) % loading rhd file
        BatchSaveJTtimes(app,FileNum) % saving JTtime
        SettingDrtaPresests(app,FileNum) % setting preset values created for drta
        BatchSave_ElectrodCheckboxCreate(app) % sets us the ch and trial choices
        BatchSave_GenerateEvents(app,FileNum) % generates the saved files
        BatchLoadFiles(app) % loads initial file
        ChannelsSaved(app) % determines which channels are saved
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            BatchJTtimeStartUp(app);
        end

        % Selection changed function: LoadingMethod_ButtonGroup
        function FileLoadingMethod(app, event)
            LoadingTypeUsed(app,event);
        end

        % Button pushed function: DataLoc_Button
        function LoadingData_Button(app, event)
            LoadData(app);
        end

        % Button pushed function: RunBatch_Button
        function RunBatchProcess(app, event)
            ChannelsSaved(app);
            RunningBachProcess(app);
        end

        % Button pushed function: LoadChosenFilesButton
        function LoadChosenFilesButtonPushed(app, event)

            BatchLoadFiles(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadingFilesTab = uitab(app.TabGroup);
            app.LoadingFilesTab.Title = 'Loading Files';
            app.Main_GridLayout = uigridlayout(app.LoadingFilesTab);
            app.FileChoice_GridLayout = uigridlayout(app.Main_GridLayout);
            app.LoadingFile_GridLayout = uigridlayout(app.FileChoice_GridLayout);
            app.Method_Label = uilabel(app.LoadingFile_GridLayout);
            app.Method_Label.Text = 'Method for loading files';
            app.LoadingMethod_ButtonGroup = uibuttongroup(app.LoadingFile_GridLayout);
            app.LoadingMethod_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FileLoadingMethod, true);
            app.FolderChoice_Button = uiradiobutton(app.LoadingMethod_ButtonGroup);
            app.FolderChoice_Button.Tag = 'FolderLoad';
            app.FolderChoice_Button.Text = 'Folder';
            app.FolderChoice_Button.Value = true;
            app.ListChoice_Button = uiradiobutton(app.LoadingMethod_ButtonGroup);
            app.ListChoice_Button.Tag = 'MakeList';
            app.ListChoice_Button.Visible = 'off';
            app.ListChoice_Button.Text = 'List';
            app.ExcelChoice_Button = uiradiobutton(app.LoadingMethod_ButtonGroup);
            app.ExcelChoice_Button.Tag = 'LoadExcel';
            app.ExcelChoice_Button.Visible = 'off';
            app.ExcelChoice_Button.Text = 'Excel';
            app.FolderLoc_Label = uilabel(app.LoadingFile_GridLayout);
            app.FolderLoc_Label.Text = 'Folder Location';
            app.DataLoc_TextArea = uitextarea(app.LoadingFile_GridLayout);
            app.DataLoc_TextArea.Editable = 'off';
            app.DataLoc_Button = uibutton(app.LoadingFile_GridLayout, 'push');
            app.DataLoc_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadingData_Button, true);
            app.DataLoc_Button.Text = 'Choose Folder';
            app.OdorandTrial_GridLayout = uigridlayout(app.LoadingFile_GridLayout);
            app.dt_Label = uilabel(app.OdorandTrial_GridLayout);
            app.dt_Label.Text = 'dt before odor end (s)';
            app.Trial_Label = uilabel(app.OdorandTrial_GridLayout);
            app.Trial_Label.Text = 'Trial Duration (s)';
            app.dt_amt = uieditfield(app.OdorandTrial_GridLayout, 'numeric');
            app.trial_amt = uieditfield(app.OdorandTrial_GridLayout, 'numeric');
            app.LoadedFiles_GridLayout = uigridlayout(app.FileChoice_GridLayout);
            app.FileList_TextArea = uitextarea(app.LoadedFiles_GridLayout);
            app.FileList_TextArea.Editable = 'off';
            app.Filesloaded_Label = uilabel(app.LoadedFiles_GridLayout);
            app.Filesloaded_Label.Text = 'Files loaded and Processing type';
            app.ProcesMethod_GridLayout = uigridlayout(app.Main_GridLayout);
            app.Processmethod_Label = uilabel(app.ProcesMethod_GridLayout);
            app.Processmethod_Label.Text = 'Processing method';
            app.LoadingProcess_DropDown = uidropdown(app.ProcesMethod_GridLayout);
            app.Savemethod_Label = uilabel(app.ProcesMethod_GridLayout);
            app.Savemethod_Label.Text = 'Save method';
            app.ProcessType_DropDown = uidropdown(app.ProcesMethod_GridLayout);
            app.AllFiles_Label = uilabel(app.ProcesMethod_GridLayout);
            app.AllFiles_Label.Visible = 'off';
            app.AllFiles_Label.Text = 'For all Files';
            app.LoadMethodAll_CheckBox = uicheckbox(app.ProcesMethod_GridLayout);
            app.LoadMethodAll_CheckBox.Visible = 'off';
            app.LoadMethodAll_CheckBox.Text = '';
            app.LoadMethodAll_CheckBox.Value = true;
            app.AllFiles_CheckBox = uicheckbox(app.ProcesMethod_GridLayout);
            app.AllFiles_CheckBox.Visible = 'off';
            app.AllFiles_CheckBox.Text = '';
            app.AllFiles_CheckBox.Value = true;
            app.fileProcess_Label = uilabel(app.ProcesMethod_GridLayout);
            app.fileProcess_Label.Text = 'Apply process to file:';
            app.FileChoice_DropDown = uidropdown(app.ProcesMethod_GridLayout);
            app.SaveChoice_Button = uibutton(app.ProcesMethod_GridLayout, 'push');
            app.SaveChoice_Button.Text = 'Save Choice';
            app.MClustCurrentFile_Label = uilabel(app.ProcesMethod_GridLayout);
            app.MClustCurrentFile_Label.Text = 'All File';
            app.IncludeMClust_Label = uilabel(app.ProcesMethod_GridLayout);
            app.IncludeMClust_Label.Text = 'Include MClust';
            app.RunMClust_CheckBox = uicheckbox(app.ProcesMethod_GridLayout);
            app.RunMClust_CheckBox.Text = '';
            app.Save_GridLayout = uigridlayout(app.Main_GridLayout);
            app.UpdatesText_Label = uilabel(app.Save_GridLayout);
            app.UpdatesText_Label.Text = 'Updates';
            app.ProgressReadout = uitextarea(app.Save_GridLayout);
            app.RunBatch_Button = uibutton(app.Save_GridLayout, 'push');
            app.RunBatch_Button.ButtonPushedFcn = createCallbackFcn(app, @RunBatchProcess, true);
            app.RunBatch_Button.Enable = 'off';
            app.RunBatch_Button.Text = 'Run Batch Processing';
            app.LoadChosenFilesButton = uibutton(app.Save_GridLayout, 'push');
            app.LoadChosenFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChosenFilesButtonPushed, true);
            app.LoadChosenFilesButton.Enable = 'off';
            app.LoadChosenFilesButton.Text = 'Load Chosen Files';
            app.SelectChannelsTab = uitab(app.TabGroup);
            app.SelectChannelsTab.Title = 'Select Channels';
            app.Channels_GridLayout = uigridlayout(app.SelectChannelsTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatchJTtime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end