classdef ExportChannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Main_GridLayout        matlab.ui.container.GridLayout
        ProgressReadout        matlab.ui.control.TextArea
        Trial_ButtonGroup      matlab.ui.container.ButtonGroup
        Alltrials_No_Button    matlab.ui.control.RadioButton
        Alltrials_Yes_Button   matlab.ui.control.RadioButton
        Channel_ButtonGroup    matlab.ui.container.ButtonGroup
        AllCh_No_Button        matlab.ui.control.RadioButton
        AllCh_Yes_Button       matlab.ui.control.RadioButton
        MClust_GridLayout      matlab.ui.container.GridLayout
        MClust_CheckBox        matlab.ui.control.CheckBox
        IncludeMClust_Label    matlab.ui.control.Label
        SaveChannel_Button     matlab.ui.control.Button
        TrialSelect_EditField  matlab.ui.control.NumericEditField
        Ch_DropDown            matlab.ui.control.DropDown
        IncludeCh_EditField    matlab.ui.control.NumericEditField
        SelectTrials_Label     matlab.ui.control.Label
        SelectCh_Label         matlab.ui.control.Label
        AllTrials_Label        matlab.ui.control.Label
        SingleCh_Label         matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp % Data from the main app
        Flags % flags that control conditions
        OutputText % This is the text that is displayed on the GUI
        SelectedCh % channel(s) being exported
        TrilesExported % trial(s) being exported
        drta_data % houses all drta created data
    end

    methods (Access = public)
        ChannelSave(app) % saves data files
        ChExportSetup(app,drtaAPP) % app setup
        featureChange(app,process) % turns off/on features
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, drtaAPP)
            
        end

        % Selection changed function: Channel_ButtonGroup
        function MultipleChSelect(app, event)
            featureChange(app,1);
        end

        % Selection changed function: Trial_ButtonGroup
        function TrialSelect(app, event)
            featureChange(app,2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Main_GridLayout = uigridlayout(app.UIFigure);
            app.SingleCh_Label = uilabel(app.Main_GridLayout);
            app.SingleCh_Label.Text = 'Single Channel';
            app.AllTrials_Label = uilabel(app.Main_GridLayout);
            app.AllTrials_Label.Text = 'All Trials';
            app.SelectCh_Label = uilabel(app.Main_GridLayout);
            app.SelectCh_Label.Text = 'Select Channel';
            app.SelectTrials_Label = uilabel(app.Main_GridLayout);
            app.SelectTrials_Label.Visible = 'off';
            app.SelectTrials_Label.Text = 'Select Trials';
            app.IncludeCh_EditField = uieditfield(app.Main_GridLayout, 'numeric');
            app.IncludeCh_EditField.Enable = 'off';
            app.IncludeCh_EditField.Visible = 'off';
            app.Ch_DropDown = uidropdown(app.Main_GridLayout);
            app.Ch_DropDown.Items = {'...'};
            app.Ch_DropDown.Value = '...';
            app.TrialSelect_EditField = uieditfield(app.Main_GridLayout, 'numeric');
            app.TrialSelect_EditField.Enable = 'off';
            app.TrialSelect_EditField.Visible = 'off';
            app.SaveChannel_Button = uibutton(app.Main_GridLayout, 'push');
            app.SaveChannel_Button.Enable = 'off';
            app.SaveChannel_Button.Text = 'Save Channel';
            app.MClust_GridLayout = uigridlayout(app.Main_GridLayout);
            app.IncludeMClust_Label = uilabel(app.MClust_GridLayout);
            app.IncludeMClust_Label.Text = 'Include MClust';
            app.MClust_CheckBox = uicheckbox(app.MClust_GridLayout);
            app.MClust_CheckBox.Text = '';
            app.Channel_ButtonGroup = uibuttongroup(app.Main_GridLayout);
            app.Channel_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MultipleChSelect, true);
            app.AllCh_Yes_Button = uiradiobutton(app.Channel_ButtonGroup);
            app.AllCh_Yes_Button.Text = 'Yes';
            app.AllCh_Yes_Button.Value = true;
            app.AllCh_No_Button = uiradiobutton(app.Channel_ButtonGroup);
            app.AllCh_No_Button.Text = 'No';
            app.Trial_ButtonGroup = uibuttongroup(app.Main_GridLayout);
            app.Trial_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrialSelect, true);
            app.Alltrials_Yes_Button = uiradiobutton(app.Trial_ButtonGroup);
            app.Alltrials_Yes_Button.Text = 'Yes';
            app.Alltrials_Yes_Button.Value = true;
            app.Alltrials_No_Button = uiradiobutton(app.Trial_ButtonGroup);
            app.Alltrials_No_Button.Text = 'No';
            app.ProgressReadout = uitextarea(app.Main_GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportChannel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end