classdef NWBmakefile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FileSettingsTab               matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        LoadPreset_Button             matlab.ui.control.Button
        LoadPreset_Label              matlab.ui.control.Label
        SaveNWBfile_Button            matlab.ui.control.Button
        LabName_EditField             matlab.ui.control.EditField
        LabName_Label                 matlab.ui.control.Label
        SessionDescp_EditField        matlab.ui.control.EditField
        nwbFileName_EditField         matlab.ui.control.EditField
        SessiondescriptionNote1Note2Label  matlab.ui.control.Label
        NWBfilenameLabel              matlab.ui.control.Label
        SubjectMetadataSettingsTab    matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        SubAge_EditField              matlab.ui.control.EditField
        Subjectage_Label              matlab.ui.control.Label
        SubSex_DropDown               matlab.ui.control.DropDown
        DOBday_EditField              matlab.ui.control.EditField
        DOBmonth_EditField            matlab.ui.control.EditField
        DOByear_EditField             matlab.ui.control.EditField
        DOB_ButtonGroup               matlab.ui.container.ButtonGroup
        DOB_NoButton                  matlab.ui.control.RadioButton
        DOB_YesButton                 matlab.ui.control.RadioButton
        SubStrain_EditField           matlab.ui.control.EditField
        SubWeight_EditField           matlab.ui.control.EditField
        SubID_EditField               matlab.ui.control.EditField
        Species_EditField             matlab.ui.control.EditField
        SubGenotype_EditField         matlab.ui.control.EditField
        SubDescrp_EditField           matlab.ui.control.EditField
        DOBday_Label                  matlab.ui.control.Label
        DOBmonth_Label                matlab.ui.control.Label
        DOByear_Label                 matlab.ui.control.Label
        UseDOB_Label                  matlab.ui.control.Label
        Subjectstrain_Label           matlab.ui.control.Label
        Subjectweightkg_Label         matlab.ui.control.Label
        SubjectID_Label               matlab.ui.control.Label
        Subjectspecies_Label          matlab.ui.control.Label
        Subjectsex_Label              matlab.ui.control.Label
        Subjectgenotype_Label         matlab.ui.control.Label
        Subjectdescription_Label      matlab.ui.control.Label
        SessionStartTimeSettingsTab   matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        StimDescription_EditField     matlab.ui.control.EditField
        Stimulusdescription_Label     matlab.ui.control.Label
        AddingStimStamps_ButtonGroup  matlab.ui.container.ButtonGroup
        NoStimStamp_Button            matlab.ui.control.RadioButton
        YesStimStamp_Button           matlab.ui.control.RadioButton
        StimulusStamps_Label          matlab.ui.control.Label
        DisplayInterval_EditField     matlab.ui.control.NumericEditField
        SampleRate_EditField          matlab.ui.control.NumericEditField
        DisplayInterval_Label         matlab.ui.control.Label
        SampleRate_Label              matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        DisplayIntervalButton         matlab.ui.control.RadioButton
        SampleRateButton              matlab.ui.control.RadioButton
        TimeRateChoice_Label          matlab.ui.control.Label
        TimeSeriesDescrp_EditField    matlab.ui.control.EditField
        TimeSeriesDescrp_Label        matlab.ui.control.Label
        ElectrodeSettingsTab          matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        ChNumPerShank_EditField       matlab.ui.control.NumericEditField
        ChNumPerShank_Label           matlab.ui.control.Label
        ShankNum_EditField            matlab.ui.control.NumericEditField
        ShankNum_Label                matlab.ui.control.Label
        DeviceSeralNum_EditField      matlab.ui.control.EditField
        DeviceSerialNum_Label         matlab.ui.control.Label
        ModelName_EditField           matlab.ui.control.EditField
        ModelName_Label               matlab.ui.control.Label
        DeviceModelNum_EditField      matlab.ui.control.EditField
        DeviceModelNum_Label          matlab.ui.control.Label
        DeviceManufacturer_EditField  matlab.ui.control.EditField
        DeviceManufactureer_Label     matlab.ui.control.Label
        DeviceDescription_EditField   matlab.ui.control.EditField
        DeviceDescription_Label       matlab.ui.control.Label
        RecordingInfo_Label           matlab.ui.control.Label
    end

    
    properties (Access = public)
        nwb % Description
        drta % structure from main app
        Prefill % prefilled information
        TimeTable % Recording time table
    end

    methods (Access = public)
        NWBfileSetup(app) % creates the NWB file class and adds the general information
        addSubProperty(app) % creates the subject information and adds to NWB file
        StartupNWB(app,varargin) % start up function to add recorded data and set any vars.
        PresetLoading(app) % loading presets
        ElectrodeProperties(app) % adding electrod data to file
        TimeSeriesProperty(app) % adding time series data to file
        CreateNWB(app) % creates NWB file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            StartupNWB(app,varargin);
        end

        % Button pushed function: LoadPreset_Button
        function LoadPreset(app, event)
            PresetLoading(app);
        end

        % Button pushed function: SaveNWBfile_Button
        function SaveNWBfile(app, event)
            CreateNWB(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileSettingsTab = uitab(app.TabGroup);
            app.FileSettingsTab.Title = 'File Settings';
            app.GridLayout = uigridlayout(app.FileSettingsTab);
            app.NWBfilenameLabel = uilabel(app.GridLayout);
            app.NWBfilenameLabel.Text = 'NWB filename';
            app.SessiondescriptionNote1Note2Label = uilabel(app.GridLayout);
            app.SessiondescriptionNote1Note2Label.Text = 'Session description [Note1 + Note2 ...]';
            app.nwbFileName_EditField = uieditfield(app.GridLayout, 'text');
            app.SessionDescp_EditField = uieditfield(app.GridLayout, 'text');
            app.LabName_Label = uilabel(app.GridLayout);
            app.LabName_Label.Text = 'Laboratory experiment was performed';
            app.LabName_EditField = uieditfield(app.GridLayout, 'text');
            app.SaveNWBfile_Button = uibutton(app.GridLayout, 'push');
            app.SaveNWBfile_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveNWBfile, true);
            app.SaveNWBfile_Button.Text = 'Save NWB file';
            app.LoadPreset_Label = uilabel(app.GridLayout);
            app.LoadPreset_Label.Text = 'Load Preset Values';
            app.LoadPreset_Button = uibutton(app.GridLayout, 'push');
            app.LoadPreset_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadPreset, true);
            app.SubjectMetadataSettingsTab = uitab(app.TabGroup);
            app.SubjectMetadataSettingsTab.Title = 'Subject Metadata Settings';
            app.GridLayout3 = uigridlayout(app.SubjectMetadataSettingsTab);
            app.Subjectdescription_Label = uilabel(app.GridLayout3);
            app.Subjectdescription_Label.Text = 'Subject description';
            app.Subjectgenotype_Label = uilabel(app.GridLayout3);
            app.Subjectgenotype_Label.Text = 'Subject genotype';
            app.Subjectsex_Label = uilabel(app.GridLayout3);
            app.Subjectsex_Label.Text = 'Subject sex';
            app.Subjectspecies_Label = uilabel(app.GridLayout3);
            app.Subjectspecies_Label.Text = 'Subject species';
            app.SubjectID_Label = uilabel(app.GridLayout3);
            app.SubjectID_Label.Text = 'Subject ID';
            app.Subjectweightkg_Label = uilabel(app.GridLayout3);
            app.Subjectweightkg_Label.Text = 'Subject weight (kg)';
            app.Subjectstrain_Label = uilabel(app.GridLayout3);
            app.Subjectstrain_Label.Text = 'Subject strain';
            app.UseDOB_Label = uilabel(app.GridLayout3);
            app.UseDOB_Label.Text = 'Use DOB ';
            app.DOByear_Label = uilabel(app.GridLayout3);
            app.DOByear_Label.Text = 'Subject DOB year [2009]';
            app.DOBmonth_Label = uilabel(app.GridLayout3);
            app.DOBmonth_Label.Text = 'Subject DOB month [9]';
            app.DOBday_Label = uilabel(app.GridLayout3);
            app.DOBday_Label.Text = 'Subject DOB day [21]';
            app.SubDescrp_EditField = uieditfield(app.GridLayout3, 'text');
            app.SubGenotype_EditField = uieditfield(app.GridLayout3, 'text');
            app.Species_EditField = uieditfield(app.GridLayout3, 'text');
            app.Species_EditField.Value = 'Mus musculus';
            app.SubID_EditField = uieditfield(app.GridLayout3, 'text');
            app.SubWeight_EditField = uieditfield(app.GridLayout3, 'text');
            app.SubStrain_EditField = uieditfield(app.GridLayout3, 'text');
            app.DOB_ButtonGroup = uibuttongroup(app.GridLayout3);
            app.DOB_YesButton = uiradiobutton(app.DOB_ButtonGroup);
            app.DOB_YesButton.Text = 'Yes';
            app.DOB_YesButton.Value = true;
            app.DOB_NoButton = uiradiobutton(app.DOB_ButtonGroup);
            app.DOB_NoButton.Text = 'No';
            app.DOByear_EditField = uieditfield(app.GridLayout3, 'text');
            app.DOBmonth_EditField = uieditfield(app.GridLayout3, 'text');
            app.DOBday_EditField = uieditfield(app.GridLayout3, 'text');
            app.SubSex_DropDown = uidropdown(app.GridLayout3);
            app.SubSex_DropDown.Items = {'...', 'M', 'F', 'U', 'O'};
            app.SubSex_DropDown.Value = '...';
            app.Subjectage_Label = uilabel(app.GridLayout3);
            app.Subjectage_Label.Enable = 'off';
            app.Subjectage_Label.Visible = 'off';
            app.Subjectage_Label.Text = 'Subject age';
            app.SubAge_EditField = uieditfield(app.GridLayout3, 'text');
            app.SubAge_EditField.Enable = 'off';
            app.SubAge_EditField.Visible = 'off';
            app.SessionStartTimeSettingsTab = uitab(app.TabGroup);
            app.SessionStartTimeSettingsTab.Title = 'Session Start Time Settings';
            app.GridLayout2 = uigridlayout(app.SessionStartTimeSettingsTab);
            app.TimeSeriesDescrp_Label = uilabel(app.GridLayout2);
            app.TimeSeriesDescrp_Label.Text = 'Time series description';
            app.TimeSeriesDescrp_EditField = uieditfield(app.GridLayout2, 'text');
            app.TimeRateChoice_Label = uilabel(app.GridLayout2);
            app.TimeRateChoice_Label.Text = 'Use sample rate or display interval';
            app.ButtonGroup = uibuttongroup(app.GridLayout2);
            app.SampleRateButton = uiradiobutton(app.ButtonGroup);
            app.SampleRateButton.Text = 'Sample Rate';
            app.DisplayIntervalButton = uiradiobutton(app.ButtonGroup);
            app.DisplayIntervalButton.Text = 'Display Interval';
            app.DisplayIntervalButton.Value = true;
            app.SampleRate_Label = uilabel(app.GridLayout2);
            app.SampleRate_Label.Enable = 'off';
            app.SampleRate_Label.Visible = 'off';
            app.SampleRate_Label.Text = 'Sample Rate';
            app.DisplayInterval_Label = uilabel(app.GridLayout2);
            app.DisplayInterval_Label.Text = 'Display Interval';
            app.SampleRate_EditField = uieditfield(app.GridLayout2, 'numeric');
            app.SampleRate_EditField.Enable = 'off';
            app.SampleRate_EditField.Visible = 'off';
            app.DisplayInterval_EditField = uieditfield(app.GridLayout2, 'numeric');
            app.DisplayInterval_EditField.Value = 9;
            app.StimulusStamps_Label = uilabel(app.GridLayout2);
            app.StimulusStamps_Label.Text = 'Add stimulus time stamps';
            app.AddingStimStamps_ButtonGroup = uibuttongroup(app.GridLayout2);
            app.YesStimStamp_Button = uiradiobutton(app.AddingStimStamps_ButtonGroup);
            app.YesStimStamp_Button.Text = 'Yes';
            app.NoStimStamp_Button = uiradiobutton(app.AddingStimStamps_ButtonGroup);
            app.NoStimStamp_Button.Text = 'No';
            app.NoStimStamp_Button.Value = true;
            app.Stimulusdescription_Label = uilabel(app.GridLayout2);
            app.Stimulusdescription_Label.Enable = 'off';
            app.Stimulusdescription_Label.Visible = 'off';
            app.Stimulusdescription_Label.Text = 'Stimulus description';
            app.StimDescription_EditField = uieditfield(app.GridLayout2, 'text');
            app.StimDescription_EditField.Enable = 'off';
            app.StimDescription_EditField.Visible = 'off';
            app.ElectrodeSettingsTab = uitab(app.TabGroup);
            app.ElectrodeSettingsTab.Title = 'Electrode Settings';
            app.GridLayout4 = uigridlayout(app.ElectrodeSettingsTab);
            app.RecordingInfo_Label = uilabel(app.GridLayout4);
            app.RecordingInfo_Label.Text = 'Recording device information:';
            app.DeviceDescription_Label = uilabel(app.GridLayout4);
            app.DeviceDescription_Label.Text = 'Description -';
            app.DeviceDescription_EditField = uieditfield(app.GridLayout4, 'text');
            app.DeviceManufactureer_Label = uilabel(app.GridLayout4);
            app.DeviceManufactureer_Label.Text = 'Manufacturer -';
            app.DeviceManufacturer_EditField = uieditfield(app.GridLayout4, 'text');
            app.DeviceModelNum_Label = uilabel(app.GridLayout4);
            app.DeviceModelNum_Label.Text = 'Model number -';
            app.DeviceModelNum_EditField = uieditfield(app.GridLayout4, 'text');
            app.ModelName_Label = uilabel(app.GridLayout4);
            app.ModelName_Label.Text = 'Model name -';
            app.ModelName_EditField = uieditfield(app.GridLayout4, 'text');
            app.DeviceSerialNum_Label = uilabel(app.GridLayout4);
            app.DeviceSerialNum_Label.Text = 'Serial number -';
            app.DeviceSeralNum_EditField = uieditfield(app.GridLayout4, 'text');
            app.ShankNum_Label = uilabel(app.GridLayout4);
            app.ShankNum_Label.Text = 'Number of shanks -';
            app.ShankNum_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.ChNumPerShank_Label = uilabel(app.GridLayout4);
            app.ChNumPerShank_Label.Text = 'Number of channels per shank -';
            app.ChNumPerShank_EditField = uieditfield(app.GridLayout4, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NWBmakefile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end