classdef drtaNWB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        drta_loadTab                matlab.ui.container.Tab
        GridLayoutLoad              matlab.ui.container.GridLayout
        CreateNWBfile_Button        matlab.ui.control.Button
        MetaDataProtocolLabel       matlab.ui.control.Label
        DisplayProtocolLabel        matlab.ui.control.Label
        Cprogrom_DropDown           matlab.ui.control.DropDown
        Savematfile_Button          matlab.ui.control.Button
        OutputLocationLabel         matlab.ui.control.Label
        LocationForOutput           matlab.ui.control.TextArea
        ChooseOutputLocationButton  matlab.ui.control.Button
        file_name                   matlab.ui.control.TextArea
        trial_amt                   matlab.ui.control.NumericEditField
        Trial_Label                 matlab.ui.control.Label
        dt_amt                      matlab.ui.control.NumericEditField
        dt_Label                    matlab.ui.control.Label
        protocolDropDown            matlab.ui.control.DropDown
        ProgressReadout             matlab.ui.control.TextArea
        Load_Button                 matlab.ui.control.StateButton
        copyright_Label             matlab.ui.control.Label
        SelectFile_Button           matlab.ui.control.StateButton
        Browse_TracesTab            matlab.ui.container.Tab
        GridLayoutTraces            matlab.ui.container.GridLayout
        SaveChennels_Button         matlab.ui.control.Button
        Filterorder_EditField       matlab.ui.control.NumericEditField
        Filterorder_Label           matlab.ui.control.Label
        StatusLFP_TextArea          matlab.ui.control.TextArea
        ShiftDropcBitand_EditField  matlab.ui.control.NumericEditField
        DataShiftBitand_EditField   matlab.ui.control.NumericEditField
        Shift_dropc_Bitand_Label    matlab.ui.control.Label
        Data_Shift_Bitand_Label     matlab.ui.control.Label
        UpdateLFPPlot_Button        matlab.ui.control.Button
        ExtraFigureOptions_Button   matlab.ui.control.StateButton
        Plot_Panel                  matlab.ui.container.Panel
        Plot_mainGrid               matlab.ui.container.GridLayout
        figurePlot_UIAxes           matlab.ui.control.UIAxes
        TrialOutcome                matlab.ui.control.Label
        SaveBrowseTracesPlotButton  matlab.ui.control.StateButton
        thr_Label                   matlab.ui.control.Label
        thr_amt                     matlab.ui.control.NumericEditField
        nxSD_amt                    matlab.ui.control.NumericEditField
        nxSD_Label                  matlab.ui.control.Label
        max_amt                     matlab.ui.control.NumericEditField
        max_Label                   matlab.ui.control.Label
        min_Label                   matlab.ui.control.Label
        min_amt                     matlab.ui.control.NumericEditField
        Diff_CheckBox               matlab.ui.control.CheckBox
        MClust_Button               matlab.ui.control.StateButton
        Licks_CheckBox              matlab.ui.control.CheckBox
        Yrange_DropDown             matlab.ui.control.DropDown
        Choice_DropDown             matlab.ui.control.DropDown
        Tnum_Label                  matlab.ui.control.Label
        Interval_Label              matlab.ui.control.Label
        Interval_amt                matlab.ui.control.NumericEditField
        Tnum_amt                    matlab.ui.control.NumericEditField
        Tnum_Rarrow                 matlab.ui.control.Button
        Tnum_Larrow                 matlab.ui.control.Button
        Yrange_amt                  matlab.ui.control.NumericEditField
        Yrange_Label                matlab.ui.control.Label
        uV_Label                    matlab.ui.control.Label
        SelectChannelsTab           matlab.ui.container.Tab
        ChannelPanel                matlab.ui.container.Panel
        Channels_GridLayout         matlab.ui.container.GridLayout
        Digital_Traces_Tab          matlab.ui.container.Tab
        DigitalMain_GridLayout      matlab.ui.container.GridLayout
        ControlsLabel               matlab.ui.control.Label
        DigitalTracesLabel          matlab.ui.control.Label
        DigitalControls_Grid        matlab.ui.container.GridLayout
        PID_CheckBox                matlab.ui.control.CheckBox
        ShowPIDfigures_Label        matlab.ui.control.Label
        StatusDiff_TextArea         matlab.ui.control.TextArea
        Trace_ylimtsMax_EditField   matlab.ui.control.NumericEditField
        Trace_ylimitsMin_EditField  matlab.ui.control.NumericEditField
        Trace_ylimits_Label         matlab.ui.control.Label
        ShiftDataBitand_EditField   matlab.ui.control.NumericEditField
        Shiftdatabitand_Label       matlab.ui.control.Label
        ShiftBitand_EditField       matlab.ui.control.NumericEditField
        Shiftdropcbitand_Label      matlab.ui.control.Label
        UpdateDigitPlots_Button     matlab.ui.control.Button
        IntervalSecDigit_EditField  matlab.ui.control.NumericEditField
        IntervalSecDigit_Label      matlab.ui.control.Label
        TrialNoDigitNext_Button     matlab.ui.control.Button
        TrialNoDigitPrior_Button    matlab.ui.control.Button
        TrialNoDigit_EditField      matlab.ui.control.NumericEditField
        TrialNoDigit_Label          matlab.ui.control.Label
        ExcLicks_Label              matlab.ui.control.Label
        ExcLicks_CheckBox           matlab.ui.control.CheckBox
        DigitalPlot_Grid            matlab.ui.container.GridLayout
        Trigger_Plot                matlab.ui.control.UIAxes
        Sniffing_Plot               matlab.ui.control.UIAxes
        Lick_Plot                   matlab.ui.control.UIAxes
        In_Port_Plot                matlab.ui.control.UIAxes
        Diode_Plot                  matlab.ui.control.UIAxes
        Digital_Plot                matlab.ui.control.UIAxes
        PIDTab                      matlab.ui.container.Tab
        PIDmain_GridLayout          matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        PIDcontrols_GridLayout      matlab.ui.container.GridLayout
        AnalogInterval_EditField    matlab.ui.control.NumericEditField
        AnalogInterval_Label        matlab.ui.control.Label
        PIDUpdatePlotButton         matlab.ui.control.Button
        PIDupdate_TextArea          matlab.ui.control.TextArea
        TrialNumberLabel            matlab.ui.control.Label
        PIDtrial_EditField          matlab.ui.control.NumericEditField
        PIDnextTrial_Button         matlab.ui.control.Button
        PIDpriorTrial_Button        matlab.ui.control.Button
        PIDendMinusOne_UIAxes       matlab.ui.control.UIAxes
        PIDendSet_UIAxes            matlab.ui.control.UIAxes
    end


    properties (Access = public)
        drta_Main % Links the main application
        drta_handles = struct(); % Description
        drta_Plot % Description
        OutputText % This is the text that is displayed on the GUI
        LFPOutputText = string; % text that gives status updates for the LFP plots
        DiffOutputText = string; % text that give status updates for diff plots
        PIDOutputText = string; % text that give status updates for PID plots
        FlagAlpha %  Flag is used to indicate the first call to readout

        %         outputTextDisplay % Houses the text for the files that are created
        NWBFileName     % File name that will be used for the NWB file
        OutputLocation % Location of output files
        BatchFiles = struct();% Structure containing all the batch file inforation
        rhd = struct(); % Structure containing current .rhd file
        h % pointer for browse traces
        Flags = struct; % Description
    end

    methods (Access = public)

        open_rhd_Callback(app) % load rhd file
        drtaOpenDG_Callback(app) % load dg file
        VarUpdate(app,Vname,Vupdate) % updates drta variable
        actualTrialNo = setTrialNo(app,trialNo) % sets the current trial number
        drtaNWB_figureControl(app) % updates the plots
        ElectrodCheckboxCreate(app) % creating electord checkboxes
        AppTypeSwitchStation(app) % switch stations for file type
        drta03_SetPreferences(app) % setting drta specific preferences
        % [varargout] = drta03_read_Intan_RHD2000_header(app) % updated function for drta 3.0
        ChooseFileToLoad(app) % choosing which file will be loaded
        LoadingChosenFile(app) % loading the file that was chosen
        ExtraImageOptions(app)
        DropChoiceDropDown(app) % sets the method for how the data is processed
        drta03_ShowDigital(app) % plotting digital figure
        VisualChoice(app) % sets the visual choice dropdown value
        TrialNumChange(app,event) % changes the trial number
        LFPplotChange(app,event) % handles user input changes for LFP plot
        SaveFile(app) % saving output
        ShowingDiffCheckbox(app) % creates the structure for dropdown obj
        PlotStatusUpdate(app,textUpdate,WhichOne) % status updates for LFP plots
        FigureSaving(app,varargin) % saving figures
        AllChControl(app,event) % all channel controls
        presetControl(app,event) % channel preset controls
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %{
            This block starts a python env that allows any library to be
            used. The batch file strucutre is started along with
            determining which mode is being requested.
            %}
            % pyenv("ExecutionMode","OutOfProcess")
            % app.BatchFiles.table = table('size',[50,1],'VariableTypes',"cell");
            % app.BatchFiles.amt = 0;
            % app.BatchFiles.totCount = 0;
            %{
            Flags for setting different conditions
            %}
            app.Flags.ChannelSetup = true;
            app.Flags.fileLoaded = false;
            %{
            Setting inital valses for calling this app in other functions
            %}
            % app.drta_handles.output = app;
            % app.drta_handles.w.drta = app;
            app.drta_handles.time_before_FV = 1;
            app.drta_handles.w.drtaBrowseTraces=0;
            app.drta_handles.w.drtaThresholdSnips=0;
            %
            drta03_SetPreferences(app)
            app.Flags.RunningWhat = 2;


        end

        % Value changed function: Yrange_amt
        function Yrange_amtValueChanged(app, event)
            %{
            This function works as intended based on the orignal guide GUI
            %}
            y_range = app.Yrange_amt.Value;
            %             v_range=y_range* app.drta_handles.draq_p.pre_gain* app.drta_handles.draq_p.daq_gain/1000000;


            if (app.drta_handles.p.which_channel==1)
                %Change for all channels
                for ii=1:app.drta_handles.draq_p.no_spike_ch
                    %         if v_range>handles.draq_p.inp_max
                    %             handles.draq_p.prev_ylim(ii)=1000000*handles.draq_p.inp_max/(handles.draq_p.pre_gain*handles.draq_p.daq_gain);
                    %         else
                    app.drta_handles.plot.s_handles(ii)=y_range;
                    app.drta_handles.draq_p.prev_ylim(ii)=y_range;
                    %         end4
                end
            else
                ii=app.drta_handles.p.which_channel-1;
                %     if v_range>handles.draq_p.inp_max
                %         handles.draq_p.prev_ylim(ii)=1000000*handles.draq_p.inp_max(2)/(handles.draq_p.pre_gain*handles.draq_p.daq_gain);
                %     else
                app.drta_handles.plot.s_handles(ii)=y_range;
                app.drta_handles.draq_p.prev_ylim(ii)=y_range;
                %     end
            end
            %             drta(app.drta_handles.w.drta,'drtaUpdateAllHandlespw',app.drta_handles)
            drtaNWB_figureControl(app);
            %             drtaNWB_PlotBrowseTraces(app.drta_handles);
        end

        % Value changed function: Interval_amt
        function Interval_amtValueChanged(app, event)
            switch event.tag
                case 'InterLFP'
                    time_interval = app.Interval_amt.Value;
                case 'InterDigi'
                    time_interval = app.IntervalSecDigit_EditField.Value;
            end

            if(app.drta_handles.p.start_display_time+time_interval)>app.drta_handles.draq_p.sec_per_trigger
                time_interval= app.drta_handles.draq_p.sec_per_trigger-app.drta_handles.p.start_display_time;
            end

            app.drta_handles.p.display_interval=time_interval;
            app.Interval_amt.Value = app.drta_handles.p.display_interval;
            app.IntervalSecDigit_EditField.Value = app.drta_handles.p.display_interval;
            %             set(hObject,'String',num2str(handles.p.display_interval));

            %             % Update all handles.p structures
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Callback function: PIDnextTrial_Button, PIDpriorTrial_Button, 
        % ...and 7 other components
        function TrialNum_ButtonPushed(app, event)
            TrialNumChange(app,event)
            textUpdate = ['Updating plot to show tiral ', ...
                num2str(app.TrialNoDigit_EditField.Value)];
            PlotStatusUpdate(app,textUpdate,1)
            PlotStatusUpdate(app,textUpdate,2)
            PlotStatusUpdate(app,textUpdate,3)
            textUpdate = sprintf('Please wait ');
            PlotStatusUpdate(app,textUpdate,1)
            PlotStatusUpdate(app,textUpdate,2)
            PlotStatusUpdate(app,textUpdate,3)
            VisualChoice(app);
            drtaNWB_figureControl(app);
            drta03_ShowDigital(app);
            GeneratePIDfigures(app);
            textUpdate = ['Plot updated, now showing tiral ' ...
                num2str(app.TrialNoDigit_EditField.Value)];
            PlotStatusUpdate(app,textUpdate,1)
            PlotStatusUpdate(app,textUpdate,2)
            PlotStatusUpdate(app,textUpdate,3)
        end

        % Value changed function: MClust_Button
        function MClust_ButtonValueChanged(app, event)
            textUpdate = "Generating MClust files, Please wait.";
            PlotStatusUpdate(app,textUpdate,1)
            app.MClust_Button.Enable = "off";
            drta03_GenerateMClust(app);
            textUpdate = "MClust files saved";
            PlotStatusUpdate(app,textUpdate,1)
        end

        % Value changed function: Licks_CheckBox
        function Licks_CheckBoxValueChanged(app, event)
            app.drta_handles.p.exc_sn = app.Licks_CheckBox.Value;
            VarUpdate(app,"exc_sn",app.drta_handles.p.exc_sn)
        end

        % Value changed function: min_amt
        function ValueAmt_Changed(app, event)
            LFPplotChange(app,event)
        end

        % Value changed function: SaveBrowseTracesPlotButton
        function SaveBrowseTracesPlotButtonValueChanged(app, event)
            textUpdate = "Saving current figure";
            if event.Source.Tag == "LFPsave"
                CurrentPlot = app.figurePlot_UIAxes;
                PlotStatusUpdate(app,textUpdate,1)
            end
            FigureSaving(app,CurrentPlot,1)
            textUpdate = "Figure saved";
            if event.Source.Tag == "LFPsave"
                PlotStatusUpdate(app,textUpdate,1)
            end
        end

        % Value changed function: Load_Button
        function Load_ButtonValueChanged(app, event)
            %{
            Loading the file that the user selected and opening the visual
            GUI for user interaction.
            %}
            LoadingChosenFile(app);
            app.Tnum_amt.Limits = [1,app.drta_handles.draq_d.noTrials];
            app.TrialNoDigit_EditField.Limits = [1,app.drta_handles.draq_d.noTrials];
            app.PIDtrial_EditField.Limits = [1,app.drta_handles.draq_d.noTrials];
            disp("stop to add limits")
        end

        % Value changed function: SelectFile_Button
        function SelectFile_ButtonValueChanged(app, event)
            %{
            Calling a user interface to select the file that needs to be
            opened. The output is the file name and path.
            %}
            ChooseFileToLoad(app);
        end

        % Callback function: ChooseOutputLocationButton, LocationForOutput
        function ChooseOutputLocationButtonPushed(app, event)
            switch event.Source.Tag
                case 'buttonOutput'
                    [Fpath] = uigetdir();
                    app.LocationForOutput.Value = Fpath;
                    app.ChooseOutputLocationButton.Visible = "off";
                    app.OutputLocationLabel.Visible = "on";
                    app.Savematfile_Button.Visible = "on";
                case 'manualOutput'
                    Fpath = app.LocationForOutput.Value;
            end
            app.OutputLocation = Fpath;
            
            
        end

        % Value changed function: Diff_CheckBox
        function Diff_CheckBoxValueChanged(app, event)
            ShowingDiffCheckbox(app)
        end

        % Callback function: Browse_TracesTab, UpdateLFPPlot_Button
        function Browse_TracesTabButtonDown(app, event)
            textUpdate = "Updating Plot, Please wait.";
            PlotStatusUpdate(app,textUpdate,1)
            if app.Flags.fileLoaded == true
                VisualChoice(app);
                drtaNWB_figureControl(app);
            end
            textUpdate = "Plot Updated";
            PlotStatusUpdate(app,textUpdate,1)
        end

        % Value changed function: ExtraFigureOptions_Button
        function ExtraFigureOptions_ButtonValueChanged(app, event)
            if app.ExtraFigureOptions_Button.Value == 1
                app.ExtraFigureOptions_Button.Enable = "off";
                ExtraFigOptions(app);
            end
            
        end

        % Callback function: Digital_Traces_Tab, UpdateDigitPlots_Button
        function UpdateDigitPlots_ButtonPushed(app, event)
            textUpdate = "Updating Plot, Please wait.";
            PlotStatusUpdate(app,textUpdate,2);
            drta03_ShowDigital(app);
            textUpdate = "Plot Updated";
            PlotStatusUpdate(app,textUpdate,2);
        end

        % Button pushed function: Savematfile_Button
        function Savematfile_ButtonPushed(app, event)
            ReadoutUpdate(app,'Saving jt_times file.')
            SaveFile(app)
            ReadoutUpdate(app,'File saved.')
        end

        % Value changed function: Yrange_DropDown
        function Yrange_DropDownValueChanged(app, event)
            value = app.Yrange_DropDown.Value;
            if value ~= "all"
                app.drta_handles.p.which_channel = str2double(value);
            end
        end

        % Button pushed function: CreateNWBfile_Button
        function CreateNWBfile(app, event)
            NWBmakefile(app);
        end

        % Value changed function: PID_CheckBox
        function PID_CheckBoxValueChanged(app, event)
            if app.PID_CheckBox.Value == 1
                app.PIDmain_GridLayout.Visible = "on";
                app.PIDtrial_EditField.Value = app.drta_handles.p.trialNo;
            else
                app.PIDmain_GridLayout.Visible = "off";
            end

        end

        % Callback function: PIDTab, PIDUpdatePlotButton
        function PIDTab_update(app, event)
            if app.PID_CheckBox.Value == 1 && app.Flags.ShowAnalog == 0
                app.PIDmain_GridLayout.Visible = "on";
                app.Flags.ShowAnalog = 1;
            elseif app.PID_CheckBox.Value == 0 && app.Flags.ShowAnalog == 1
                app.PIDmain_GridLayout.Visible = "off";
                app.Flags.ShowAnalog = 0;
            end
            if app.Flags.ShowAnalog == 1
                textUpdate = "Updating Plot, Please wait.";
                PlotStatusUpdate(app,textUpdate,3);
                GeneratePIDfigures(app);
                % textUpdate = "Plot Updated";
                % PlotStatusUpdate(app,textUpdate,3);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.drta_loadTab = uitab(app.TabGroup);
            app.drta_loadTab.Title = 'drta_load';
            app.GridLayoutLoad = uigridlayout(app.drta_loadTab);
            app.SelectFile_Button = uibutton(app.GridLayoutLoad, 'state');
            app.SelectFile_Button.ValueChangedFcn = createCallbackFcn(app, @SelectFile_ButtonValueChanged, true);
            app.SelectFile_Button.Text = 'Choose File (.rhd or .edf)';
            app.copyright_Label = uilabel(app.GridLayoutLoad);
            app.copyright_Label.Text = 'drta version 3.0 copyright Diego Restrepo';
            app.Load_Button = uibutton(app.GridLayoutLoad, 'state');
            app.Load_Button.ValueChangedFcn = createCallbackFcn(app, @Load_ButtonValueChanged, true);
            app.Load_Button.Visible = 'off';
            app.Load_Button.Text = 'Load File';
            app.ProgressReadout = uitextarea(app.GridLayoutLoad);
            app.protocolDropDown = uidropdown(app.GridLayoutLoad);
            app.protocolDropDown.Items = {'...'};
            app.protocolDropDown.Visible = 'off';
            app.protocolDropDown.Value = '...';
            app.dt_Label = uilabel(app.GridLayoutLoad);
            app.dt_Label.Visible = 'off';
            app.dt_Label.Text = 'dt before odor end (s)';
            app.dt_amt = uieditfield(app.GridLayoutLoad, 'numeric');
            app.dt_amt.Visible = 'off';
            app.dt_amt.Value = 6;
            app.Trial_Label = uilabel(app.GridLayoutLoad);
            app.Trial_Label.Visible = 'off';
            app.Trial_Label.Text = 'Trial duration (s)';
            app.trial_amt = uieditfield(app.GridLayoutLoad, 'numeric');
            app.trial_amt.Visible = 'off';
            app.trial_amt.Value = 9;
            app.file_name = uitextarea(app.GridLayoutLoad);
            app.file_name.Value = {'No File Selected'};
            app.ChooseOutputLocationButton = uibutton(app.GridLayoutLoad, 'push');
            app.ChooseOutputLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseOutputLocationButtonPushed, true);
            app.ChooseOutputLocationButton.Tag = 'buttonOutput';
            app.ChooseOutputLocationButton.Text = 'Choose Output Location';
            app.LocationForOutput = uitextarea(app.GridLayoutLoad);
            app.LocationForOutput.ValueChangedFcn = createCallbackFcn(app, @ChooseOutputLocationButtonPushed, true);
            app.LocationForOutput.Tag = 'manualOutput';
            app.OutputLocationLabel = uilabel(app.GridLayoutLoad);
            app.OutputLocationLabel.Visible = 'off';
            app.OutputLocationLabel.Text = 'Output Location';
            app.Savematfile_Button = uibutton(app.GridLayoutLoad, 'push');
            app.Savematfile_Button.ButtonPushedFcn = createCallbackFcn(app, @Savematfile_ButtonPushed, true);
            app.Savematfile_Button.Enable = 'off';
            app.Savematfile_Button.Text = 'Save jt_Time file';
            app.Cprogrom_DropDown = uidropdown(app.GridLayoutLoad);
            app.Cprogrom_DropDown.Items = {'dropcnsampler', 'dropcspm', 'background', 'spmult', 'mspy', 'osampler', 'spm2mult', 'lighton1', 'lighton5', 'dropcspm_conc', 'Laser-triggered', 'Laser-Merouann', 'dropcspm_hf', 'Working_memoy', 'Continuous', 'Laser-Kira', 'Laser-Schoppa'};
            app.Cprogrom_DropDown.Visible = 'off';
            app.Cprogrom_DropDown.Value = 'dropcnsampler';
            app.DisplayProtocolLabel = uilabel(app.GridLayoutLoad);
            app.DisplayProtocolLabel.Visible = 'off';
            app.DisplayProtocolLabel.Text = 'Display Protocol';
            app.MetaDataProtocolLabel = uilabel(app.GridLayoutLoad);
            app.MetaDataProtocolLabel.Visible = 'off';
            app.MetaDataProtocolLabel.Text = 'Meta Data Protocol';
            app.CreateNWBfile_Button = uibutton(app.GridLayoutLoad, 'push');
            app.CreateNWBfile_Button.ButtonPushedFcn = createCallbackFcn(app, @CreateNWBfile, true);
            app.CreateNWBfile_Button.Enable = 'off';
            app.CreateNWBfile_Button.Visible = 'off';
            app.CreateNWBfile_Button.Text = 'Create NWB file';
            app.Browse_TracesTab = uitab(app.TabGroup);
            app.Browse_TracesTab.Title = 'Browse_Traces';
            app.Browse_TracesTab.ButtonDownFcn = createCallbackFcn(app, @Browse_TracesTabButtonDown, true);
            app.GridLayoutTraces = uigridlayout(app.Browse_TracesTab);
            app.uV_Label = uilabel(app.GridLayoutTraces);
            app.uV_Label.Text = 'uV';
            app.Yrange_Label = uilabel(app.GridLayoutTraces);
            app.Yrange_Label.Text = 'Y Range (uV):';
            app.Yrange_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Yrange_amt.ValueChangedFcn = createCallbackFcn(app, @Yrange_amtValueChanged, true);
            app.Yrange_amt.Value = 4000;
            app.Tnum_Larrow = uibutton(app.GridLayoutTraces, 'push');
            app.Tnum_Larrow.ButtonPushedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.Tnum_Larrow.Tag = 'TnumMinus';
            app.Tnum_Larrow.Text = '<--';
            app.Tnum_Rarrow = uibutton(app.GridLayoutTraces, 'push');
            app.Tnum_Rarrow.ButtonPushedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.Tnum_Rarrow.Tag = 'TnumPlus';
            app.Tnum_Rarrow.Text = '-->';
            app.Tnum_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Tnum_amt.ValueChangedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.Tnum_amt.Tag = 'TnumField';
            app.Tnum_amt.Value = 1;
            app.Interval_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Interval_amt.ValueChangedFcn = createCallbackFcn(app, @Interval_amtValueChanged, true);
            app.Interval_amt.Tag = 'InterLFP';
            app.Interval_amt.Value = 1;
            app.Interval_Label = uilabel(app.GridLayoutTraces);
            app.Interval_Label.Text = 'Interval (sec):';
            app.Tnum_Label = uilabel(app.GridLayoutTraces);
            app.Tnum_Label.Text = 'Trial No:';
            app.Choice_DropDown = uidropdown(app.GridLayoutTraces);
            app.Choice_DropDown.Items = {'Raw', 'User Choice', 'Wide 4-100', 'High Theta 6-14', 'Theta 2-14', 'Beta 15-36', 'Gamma1 35-65', 'Gamma2 65-95', 'Gamma 35-95', 'Spikes 500-5000', 'Spike var'};
            app.Choice_DropDown.Value = 'Raw';
            app.Yrange_DropDown = uidropdown(app.GridLayoutTraces);
            app.Yrange_DropDown.Items = {'all', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16'};
            app.Yrange_DropDown.ValueChangedFcn = createCallbackFcn(app, @Yrange_DropDownValueChanged, true);
            app.Yrange_DropDown.Value = 'all';
            app.Licks_CheckBox = uicheckbox(app.GridLayoutTraces);
            app.Licks_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Licks_CheckBoxValueChanged, true);
            app.Licks_CheckBox.Enable = 'off';
            app.Licks_CheckBox.Text = 'Exclude Licks';
            app.MClust_Button = uibutton(app.GridLayoutTraces, 'state');
            app.MClust_Button.ValueChangedFcn = createCallbackFcn(app, @MClust_ButtonValueChanged, true);
            app.MClust_Button.Text = 'MClust';
            app.Diff_CheckBox = uicheckbox(app.GridLayoutTraces);
            app.Diff_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Diff_CheckBoxValueChanged, true);
            app.Diff_CheckBox.Text = 'Differential';
            app.min_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.min_amt.ValueChangedFcn = createCallbackFcn(app, @ValueAmt_Changed, true);
            app.min_amt.Tag = 'LFPmin';
            app.min_amt.Value = 50;
            app.min_Label = uilabel(app.GridLayoutTraces);
            app.min_Label.Text = 'LFP min';
            app.max_Label = uilabel(app.GridLayoutTraces);
            app.max_Label.Text = 'LFP max';
            app.max_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.max_amt.Tag = 'LFPmax';
            app.max_amt.Value = 3900;
            app.nxSD_Label = uilabel(app.GridLayoutTraces);
            app.nxSD_Label.Text = 'Set nxSD';
            app.nxSD_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.nxSD_amt.Tag = 'nxSD';
            app.nxSD_amt.Value = 2.5;
            app.thr_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.thr_amt.Tag = 'Thr';
            app.thr_Label = uilabel(app.GridLayoutTraces);
            app.thr_Label.Text = 'Set thr (uv)';
            app.SaveBrowseTracesPlotButton = uibutton(app.GridLayoutTraces, 'state');
            app.SaveBrowseTracesPlotButton.ValueChangedFcn = createCallbackFcn(app, @SaveBrowseTracesPlotButtonValueChanged, true);
            app.SaveBrowseTracesPlotButton.Tag = 'LFPsave';
            app.SaveBrowseTracesPlotButton.Text = 'Save Browse Traces Plot';
            app.TrialOutcome = uilabel(app.GridLayoutTraces);
            app.TrialOutcome.Text = 'Trial';
            app.Plot_Panel = uipanel(app.GridLayoutTraces);
            app.Plot_mainGrid = uigridlayout(app.Plot_Panel);
            app.figurePlot_UIAxes = uiaxes(app.Plot_mainGrid);
            app.ExtraFigureOptions_Button = uibutton(app.GridLayoutTraces, 'state');
            app.ExtraFigureOptions_Button.ValueChangedFcn = createCallbackFcn(app, @ExtraFigureOptions_ButtonValueChanged, true);
            app.ExtraFigureOptions_Button.Enable = 'off';
            app.ExtraFigureOptions_Button.Visible = 'off';
            app.ExtraFigureOptions_Button.Text = 'Extra Figure Options';
            app.UpdateLFPPlot_Button = uibutton(app.GridLayoutTraces, 'push');
            app.UpdateLFPPlot_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_TracesTabButtonDown, true);
            app.UpdateLFPPlot_Button.Text = 'Update LFP Plot';
            app.Data_Shift_Bitand_Label = uilabel(app.GridLayoutTraces);
            app.Data_Shift_Bitand_Label.Text = 'Data_Shift_Bitand';
            app.Shift_dropc_Bitand_Label = uilabel(app.GridLayoutTraces);
            app.Shift_dropc_Bitand_Label.Text = 'Shift_dropc_Bitand';
            app.DataShiftBitand_EditField = uieditfield(app.GridLayoutTraces, 'numeric');
            app.ShiftDropcBitand_EditField = uieditfield(app.GridLayoutTraces, 'numeric');
            app.StatusLFP_TextArea = uitextarea(app.GridLayoutTraces);
            app.StatusLFP_TextArea.Editable = 'off';
            app.Filterorder_Label = uilabel(app.GridLayoutTraces);
            app.Filterorder_Label.Text = 'Filter order';
            app.Filterorder_EditField = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Filterorder_EditField.Limits = [0 Inf];
            app.Filterorder_EditField.Value = 8;
            app.SaveChennels_Button = uibutton(app.GridLayoutTraces, 'push');
            app.SaveChennels_Button.Text = 'Save Chennel(s)';
            app.SelectChannelsTab = uitab(app.TabGroup);
            app.SelectChannelsTab.Title = 'Select Channels';
            app.ChannelPanel = uipanel(app.SelectChannelsTab);
            app.Channels_GridLayout = uigridlayout(app.ChannelPanel);
            app.Digital_Traces_Tab = uitab(app.TabGroup);
            app.Digital_Traces_Tab.Title = 'Digital_Traces';
            app.Digital_Traces_Tab.ButtonDownFcn = createCallbackFcn(app, @UpdateDigitPlots_ButtonPushed, true);
            app.DigitalMain_GridLayout = uigridlayout(app.Digital_Traces_Tab);
            app.DigitalPlot_Grid = uigridlayout(app.DigitalMain_GridLayout);
            app.Digital_Plot = uiaxes(app.DigitalPlot_Grid);
            app.Diode_Plot = uiaxes(app.DigitalPlot_Grid);
            app.In_Port_Plot = uiaxes(app.DigitalPlot_Grid);
            app.Lick_Plot = uiaxes(app.DigitalPlot_Grid);
            app.Sniffing_Plot = uiaxes(app.DigitalPlot_Grid);
            app.Trigger_Plot = uiaxes(app.DigitalPlot_Grid);
            app.DigitalControls_Grid = uigridlayout(app.DigitalMain_GridLayout);
            app.ExcLicks_CheckBox = uicheckbox(app.DigitalControls_Grid);
            app.ExcLicks_CheckBox.Enable = 'off';
            app.ExcLicks_CheckBox.Text = '';
            app.ExcLicks_Label = uilabel(app.DigitalControls_Grid);
            app.ExcLicks_Label.Text = 'Exclude Licks';
            app.TrialNoDigit_Label = uilabel(app.DigitalControls_Grid);
            app.TrialNoDigit_Label.Text = 'Trial No.';
            app.TrialNoDigit_EditField = uieditfield(app.DigitalControls_Grid, 'numeric');
            app.TrialNoDigit_EditField.ValueChangedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.TrialNoDigit_EditField.Tag = 'DigTnumField';
            app.TrialNoDigitPrior_Button = uibutton(app.DigitalControls_Grid, 'push');
            app.TrialNoDigitPrior_Button.ButtonPushedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.TrialNoDigitPrior_Button.Tag = 'TnumMinus';
            app.TrialNoDigitPrior_Button.Icon = fullfile(pathToMLAPP, 'Images', 'leftArrow.png');
            app.TrialNoDigitPrior_Button.Text = '';
            app.TrialNoDigitNext_Button = uibutton(app.DigitalControls_Grid, 'push');
            app.TrialNoDigitNext_Button.ButtonPushedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.TrialNoDigitNext_Button.Tag = 'TnumPlus';
            app.TrialNoDigitNext_Button.Icon = fullfile(pathToMLAPP, 'Images', 'rightArrow.png');
            app.TrialNoDigitNext_Button.Text = '';
            app.IntervalSecDigit_Label = uilabel(app.DigitalControls_Grid);
            app.IntervalSecDigit_Label.Text = 'Interval (sec):';
            app.IntervalSecDigit_EditField = uieditfield(app.DigitalControls_Grid, 'numeric');
            app.IntervalSecDigit_EditField.Tag = 'InterDigi';
            app.UpdateDigitPlots_Button = uibutton(app.DigitalControls_Grid, 'push');
            app.UpdateDigitPlots_Button.ButtonPushedFcn = createCallbackFcn(app, @UpdateDigitPlots_ButtonPushed, true);
            app.UpdateDigitPlots_Button.Text = 'Update Digital Plots';
            app.Shiftdropcbitand_Label = uilabel(app.DigitalControls_Grid);
            app.Shiftdropcbitand_Label.Text = 'Shift dropc bitand';
            app.ShiftBitand_EditField = uieditfield(app.DigitalControls_Grid, 'numeric');
            app.Shiftdatabitand_Label = uilabel(app.DigitalControls_Grid);
            app.Shiftdatabitand_Label.Text = 'Shift data bitand';
            app.ShiftDataBitand_EditField = uieditfield(app.DigitalControls_Grid, 'numeric');
            app.Trace_ylimits_Label = uilabel(app.DigitalControls_Grid);
            app.Trace_ylimits_Label.Text = 'Trace y-limits';
            app.Trace_ylimitsMin_EditField = uieditfield(app.DigitalControls_Grid, 'numeric');
            app.Trace_ylimitsMin_EditField.Value = 1500;
            app.Trace_ylimtsMax_EditField = uieditfield(app.DigitalControls_Grid, 'numeric');
            app.Trace_ylimtsMax_EditField.Value = 3000;
            app.StatusDiff_TextArea = uitextarea(app.DigitalControls_Grid);
            app.ShowPIDfigures_Label = uilabel(app.DigitalControls_Grid);
            app.ShowPIDfigures_Label.Text = 'Show PID figures';
            app.PID_CheckBox = uicheckbox(app.DigitalControls_Grid);
            app.PID_CheckBox.ValueChangedFcn = createCallbackFcn(app, @PID_CheckBoxValueChanged, true);
            app.PID_CheckBox.Text = '';
            app.DigitalTracesLabel = uilabel(app.DigitalMain_GridLayout);
            app.DigitalTracesLabel.Text = 'Digital Traces';
            app.ControlsLabel = uilabel(app.DigitalMain_GridLayout);
            app.ControlsLabel.Text = 'Controls';
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'PID';
            app.PIDTab.ButtonDownFcn = createCallbackFcn(app, @PIDTab_update, true);
            app.PIDmain_GridLayout = uigridlayout(app.PIDTab);
            app.PIDmain_GridLayout.Visible = 'off';
            app.PIDendSet_UIAxes = uiaxes(app.PIDmain_GridLayout);
            xlabel(app.PIDendSet_UIAxes, 'X')
            ylabel(app.PIDendSet_UIAxes, 'Y')
            zlabel(app.PIDendSet_UIAxes, 'Z')
            app.PIDendMinusOne_UIAxes = uiaxes(app.PIDmain_GridLayout);
            xlabel(app.PIDendMinusOne_UIAxes, 'X')
            ylabel(app.PIDendMinusOne_UIAxes, 'Y')
            zlabel(app.PIDendMinusOne_UIAxes, 'Z')
            app.Panel = uipanel(app.PIDmain_GridLayout);
            app.PIDcontrols_GridLayout = uigridlayout(app.Panel);
            app.PIDpriorTrial_Button = uibutton(app.PIDcontrols_GridLayout, 'push');
            app.PIDpriorTrial_Button.ButtonPushedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.PIDpriorTrial_Button.Tag = 'TnumMinus';
            app.PIDpriorTrial_Button.Icon = fullfile(pathToMLAPP, 'Images', 'leftArrow.png');
            app.PIDpriorTrial_Button.Text = '';
            app.PIDnextTrial_Button = uibutton(app.PIDcontrols_GridLayout, 'push');
            app.PIDnextTrial_Button.ButtonPushedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.PIDnextTrial_Button.Tag = 'TnumPlus';
            app.PIDnextTrial_Button.Icon = fullfile(pathToMLAPP, 'Images', 'rightArrow.png');
            app.PIDnextTrial_Button.Text = '';
            app.PIDtrial_EditField = uieditfield(app.PIDcontrols_GridLayout, 'numeric');
            app.PIDtrial_EditField.ValueChangedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.PIDtrial_EditField.Tag = 'PIDnumField';
            app.TrialNumberLabel = uilabel(app.PIDcontrols_GridLayout);
            app.TrialNumberLabel.Text = 'Trial Number:';
            app.PIDupdate_TextArea = uitextarea(app.PIDcontrols_GridLayout);
            app.PIDUpdatePlotButton = uibutton(app.PIDcontrols_GridLayout, 'push');
            app.PIDUpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @PIDTab_update, true);
            app.PIDUpdatePlotButton.Text = 'Update Plot';
            app.AnalogInterval_Label = uilabel(app.PIDcontrols_GridLayout);
            app.AnalogInterval_Label.Text = 'Interval (sec):';
            app.AnalogInterval_EditField = uieditfield(app.PIDcontrols_GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drtaNWB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end