classdef drtaNWB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        drta_loadTab                   matlab.ui.container.Tab
        GridLayoutLoad                 matlab.ui.container.GridLayout
        OutputLocationLabel            matlab.ui.control.Label
        LocationForOutput              matlab.ui.control.TextArea
        ChooseOutputLocationButton     matlab.ui.control.Button
        InputFileName                  matlab.ui.control.TextArea
        ConvertrhdFileButton           matlab.ui.control.StateButton
        OutputFileName                 matlab.ui.control.EditField
        NWBfilenameLabel               matlab.ui.control.Label
        IntanfilenameLabel             matlab.ui.control.Label
        CreateFileforConversionButton  matlab.ui.control.StateButton
        CurrentFileNumber              matlab.ui.control.NumericEditField
        NextFile                       matlab.ui.control.Button
        LastFile                       matlab.ui.control.Button
        FIlenumberProcessLabel         matlab.ui.control.Label
        FileNumber                     matlab.ui.control.NumericEditField
        NumberOfFileLabel              matlab.ui.control.Label
        NWBconvert                     matlab.ui.container.ButtonGroup
        BatchofFilesButton             matlab.ui.control.RadioButton
        SingleFileButton               matlab.ui.control.RadioButton
        drtaProcessVersion             matlab.ui.container.ButtonGroup
        drta30Button                   matlab.ui.control.RadioButton
        drta20Button                   matlab.ui.control.RadioButton
        AppType                        matlab.ui.container.ButtonGroup
        ViewandprocessrhdfileButton    matlab.ui.control.RadioButton
        rhdtoNWBButton                 matlab.ui.control.RadioButton
        file_name                      matlab.ui.control.TextArea
        trial_amt                      matlab.ui.control.NumericEditField
        Trial_Label                    matlab.ui.control.Label
        dt_amt                         matlab.ui.control.NumericEditField
        dt_Label                       matlab.ui.control.Label
        type_DropDown_2                matlab.ui.control.DropDown
        ProgressReadout                matlab.ui.control.TextArea
        Load_Button                    matlab.ui.control.StateButton
        copyright_Label                matlab.ui.control.Label
        SelectFile_Button              matlab.ui.control.StateButton
        Browse_TracesTab               matlab.ui.container.Tab
        GridLayoutTraces               matlab.ui.container.GridLayout
        NWB_CheckBox                   matlab.ui.control.CheckBox
        SaveButton                     matlab.ui.control.StateButton
        NWB_Label                      matlab.ui.control.Label
        type_DropDown                  matlab.ui.control.DropDown
        DTS_amt                        matlab.ui.control.NumericEditField
        thr_Label                      matlab.ui.control.Label
        thr_amt                        matlab.ui.control.NumericEditField
        nxSD_amt                       matlab.ui.control.NumericEditField
        nxSD_Label                     matlab.ui.control.Label
        max_amt                        matlab.ui.control.NumericEditField
        max_Label                      matlab.ui.control.Label
        min_Label                      matlab.ui.control.Label
        min_amt                        matlab.ui.control.NumericEditField
        Diff_CheckBox                  matlab.ui.control.CheckBox
        MClust_Button                  matlab.ui.control.StateButton
        Licks_CheckBox                 matlab.ui.control.CheckBox
        Yrange_DropDown                matlab.ui.control.DropDown
        Disp_DropDown                  matlab.ui.control.DropDown
        Choice_DropDown                matlab.ui.control.DropDown
        Disp_Label                     matlab.ui.control.Label
        DTS_Larrow                     matlab.ui.control.Button
        DTS_Rarrow                     matlab.ui.control.Button
        Tnum_Label                     matlab.ui.control.Label
        DTS_Label                      matlab.ui.control.Label
        Interval_Label                 matlab.ui.control.Label
        Interval_amt                   matlab.ui.control.NumericEditField
        Tnum_amt                       matlab.ui.control.NumericEditField
        Tnum_Rarrow                    matlab.ui.control.Button
        Tnum_Larrow                    matlab.ui.control.Button
        Yrange_amt                     matlab.ui.control.NumericEditField
        Yrange_Label                   matlab.ui.control.Label
        Sub_16                         matlab.ui.control.DropDown
        Sub_15                         matlab.ui.control.DropDown
        Sub_14                         matlab.ui.control.DropDown
        Sub_13                         matlab.ui.control.DropDown
        Sub_12                         matlab.ui.control.DropDown
        Sub_11                         matlab.ui.control.DropDown
        Sub_10                         matlab.ui.control.DropDown
        Sub_9                          matlab.ui.control.DropDown
        Sub_8                          matlab.ui.control.DropDown
        Sub_7                          matlab.ui.control.DropDown
        Sub_6                          matlab.ui.control.DropDown
        Sub_5                          matlab.ui.control.DropDown
        Sub_4                          matlab.ui.control.DropDown
        Sub_3                          matlab.ui.control.DropDown
        Sub_2                          matlab.ui.control.DropDown
        Sub_1                          matlab.ui.control.DropDown
        uV_Label                       matlab.ui.control.Label
        FileSettingsTab                matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        FSinput11                      matlab.ui.control.EditField
        ChooseLocationButton           matlab.ui.control.StateButton
        OutputlocationLabel            matlab.ui.control.Label
        ChooseFileButton               matlab.ui.control.StateButton
        ButtonGroup_4                  matlab.ui.container.ButtonGroup
        FSinput10no                    matlab.ui.control.RadioButton
        FSinput10yes                   matlab.ui.control.RadioButton
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        FSinput9no                     matlab.ui.control.RadioButton
        FSinput9yes                    matlab.ui.control.RadioButton
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        FSinput8no                     matlab.ui.control.RadioButton
        FSinput8yes                    matlab.ui.control.RadioButton
        ButtonGroup                    matlab.ui.container.ButtonGroup
        FSinput4no                     matlab.ui.control.RadioButton
        FSinput4yes                    matlab.ui.control.RadioButton
        FSinput7                       matlab.ui.control.EditField
        FSinput6                       matlab.ui.control.EditField
        FSinput5                       matlab.ui.control.EditField
        FSinput3                       matlab.ui.control.EditField
        FSinput2                       matlab.ui.control.EditField
        FSinput1                       matlab.ui.control.EditField
        FSinput0                       matlab.ui.control.EditField
        IncludesubjectmetadataLabel    matlab.ui.control.Label
        IncludetimesettingsLabel       matlab.ui.control.Label
        MergefilesLabel                matlab.ui.control.Label
        HighpassdescriptionLabel       matlab.ui.control.Label
        LowpassdescriptionLabel        matlab.ui.control.Label
        CompressionLevelLabel          matlab.ui.control.Label
        UseCompressionLabel            matlab.ui.control.Label
        Blocksperchunk1000Label        matlab.ui.control.Label
        SessiondescriptionNote1Note2Label  matlab.ui.control.Label
        NWBfilenameLabel_2             matlab.ui.control.Label
        IntanfilenameLabel_2           matlab.ui.control.Label
        SessionStartTimeSettingsTab    matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        TSinput5                       matlab.ui.control.EditField
        TSinput4                       matlab.ui.control.EditField
        TSinput3                       matlab.ui.control.EditField
        TSinput2                       matlab.ui.control.EditField
        TSinput1                       matlab.ui.control.EditField
        TSinput0                       matlab.ui.control.EditField
        Manualsessionstartsecond43Label  matlab.ui.control.Label
        Manualsessionstartminute23Label  matlab.ui.control.Label
        Manualsessionstarthour16Label  matlab.ui.control.Label
        manualsessionstartday17Label   matlab.ui.control.Label
        Manualsessionstartmonth1Label  matlab.ui.control.Label
        Manualsessionstartyear2019Label  matlab.ui.control.Label
        SubjectMetadataSettingsTab     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        MSinput11                      matlab.ui.control.EditField
        MSinput10                      matlab.ui.control.EditField
        MSinput9                       matlab.ui.control.EditField
        ButtonGroup_5                  matlab.ui.container.ButtonGroup
        MSinput8no                     matlab.ui.control.RadioButton
        MSinput8yes                    matlab.ui.control.RadioButton
        MSinput7                       matlab.ui.control.EditField
        MSinput6                       matlab.ui.control.EditField
        MSinput5                       matlab.ui.control.EditField
        MSinput4                       matlab.ui.control.EditField
        MSinput3                       matlab.ui.control.EditField
        MSinput2                       matlab.ui.control.EditField
        MSinput1                       matlab.ui.control.EditField
        MSinput0                       matlab.ui.control.EditField
        SubjectDOBday21Label           matlab.ui.control.Label
        SubjectDOBmonth9Label          matlab.ui.control.Label
        SubjectDOByear2009Label        matlab.ui.control.Label
        IncludesubjectDOBLabel         matlab.ui.control.Label
        SubjectstrainLabel             matlab.ui.control.Label
        SubjectweightkgLabel           matlab.ui.control.Label
        SubjectIDLabel                 matlab.ui.control.Label
        SubjectspeciesLabel            matlab.ui.control.Label
        SubjectsexLabel                matlab.ui.control.Label
        SubjectgenotypeLabel           matlab.ui.control.Label
        SubjectdescriptionLabel        matlab.ui.control.Label
        SubjectageLabel                matlab.ui.control.Label
        Save_fileTab                   matlab.ui.container.Tab
        GridLayoutSave                 matlab.ui.container.GridLayout
        SavefileButton                 matlab.ui.control.StateButton
        ProtocolTypeDropDown           matlab.ui.control.DropDown
        ProtocolTypeDropDownLabel      matlab.ui.control.Label
        CollectdatafrombrowsetracesButton  matlab.ui.control.StateButton
    end


    properties (Access = private)
        drta_Main % Links the main application
        drta_handles = struct();% Description
        drta_Plot % Description
        OutputText % This is the text that is displayed on the GUI
        FlagAlpha %  Flag is used to indicate the first call to readout
        %         outputTextDisplay % Houses the text for the files that are created
        CurrentFilePath  % Houses the current path for the file
        NWBFileName     % File name that will be used for the NWB file
        OutputLocation % Location of output files
        File_amt % Numer of files that need to be converted
        File_table % table of all the files being converted
        File_location % location of the batchfile
        File_rhdlocation % Location of .rhd file
        File_rhdname % name of the .rhd file
        File_totCount % total number of config files created
        h % pointer for browse traces
    end

    methods (Access = public)

        function open_rhd_Callback(app)

            FileName=app.drta_handles.p.FileName;
            app.drta_handles.drtaWhichFile = FileName;

            %     %Setup the matlab header file
            [app.drta_handles.draq_p,app.drta_handles.draq_d]=drta_read_Intan_RHD2000_header(app.drta_handles.p.fullName,app.drta_handles.p.which_protocol,app.drta_handles);
            % end
            app.drta_handles.GUIs.drtaLoad = app;

            app.drta_handles.draq_p.dgordra=3;


            scaling = app.drta_handles.draq_p.scaling;
            offset = app.drta_handles.draq_p.offset;

            app.drta_handles.draq_p.no_spike_ch=16;
            app.drta_handles.draq_p.daq_gain=1;
            app.drta_handles.draq_p.prev_ylim(1:17)=4000;
            app.drta_handles.draq_p.no_chans=22;
            app.drta_handles.draq_p.acquire_display_start=0;
            app.drta_handles.draq_p.inp_max=10;
            % app.drta_handles.p.trialNo=2;
            % app.drta_handles.p.low_filter=1000;
            % app.drta_handles.p.high_filter=5000;
            % app.drta_handles.p.whichPlot=1;
            % app.drta_handles.p.lastTrace=-1;
            % app.drta_handles.p.exclude_secs=2;
            % app.drta_handles.p.set2p5SD=0;
            % app.drta_handles.p.setm2p5SD=0;
            % app.drta_handles.p.setnxSD=0;
            % app.drta_handles.p.nxSD=2.5;
            % app.drta_handles.p.showLicks=1;
            % app.drta_handles.p.exc_sn=0;
            % app.drta_handles.p.read_entire_file=0;
            % app.drta_handles.p.setThr=0;
            % app.drta_handles.p.thrToSet=0;
            % app.drta_handles.p.which_protocol=1;


            if exist('drta_p','var') == 1 && isfield(drta_p,'doSubtract')
                app.drta_handles.p.doSubtract=drta_p.doSubtract;
                app.drta_handles.p.subtractCh=drta_p.subtractCh;
            else
                app.drta_handles.p.doSubtract=0;
                for i = 1:16
                    app.drta_handles.p.subtractCh{i,1}{:} = "18";
                end
            end

            for ii=1:app.drta_handles.draq_p.no_spike_ch
                try
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                catch
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                end

            end

            app.drta_handles.p.trial_ch_processed=ones(16,app.drta_handles.draq_d.noTrials);
            app.drta_handles.p.trial_allch_processed=ones(1,app.drta_handles.draq_d.noTrials);

            if (exist('drta_p')~=0)
                app.drta_handles.p.threshold=drta_p.threshold;

                if (isfield(drta_p,'ch_processed')~=0)
                    app.drta_handles.p.ch_processed=drta_p.ch_processed;
                end
                if (isfield(drta_p,'trial_ch_processed')~=0)
                    app.drta_handles.p.trial_ch_processed=drta_p.trial_ch_processed;
                end
                if (isfield(drta_p,'trial_allch_processed')~=0)
                    app.drta_handles.p.trial_allch_processed=drta_p.trial_allch_processed;
                end
                if (isfield(drta_p,'upper_limit')~=0)
                    app.drta_handles.p.upper_limit=drta_p.upper_limit;
                    app.drta_handles.p.lower_limit=drta_p.lower_limit;
                end
                if isfield(drta_p,'exc_sn')
                    app.drta_handles.p.exc_sn=drta_p.exc_sn;
                    %         app.drta_handles.p.exc_sn_thr=drta_p.exc_sn_thr;
                    %         app.drta_handles.p.exc_sn_ch=drta_p.exc_sn_ch;
                end
            end
            drtaNWB_PlotBrowseTraces(app, app.drta_handles);
        end

        function drtaOpenDG_Callback(app)
            %             drtaOpenDG_Callback(hObject, eventdata, app.drta_handles)
            % hObject    handle to drtaOpenDG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.drta_handles    structure with app.drta_handles and user data (see GUIDATA)


            FileName=app.drta_handles.p.FileName;
            set(app.drta_handles.drtaWhichFile,'String',FileName);
            load([app.drta_handles.p.fullName(1:end-2),'mat']);
            try
                app.drta_handles.draq_p=params;
                app.drta_handles.draq_d=data;
            catch
                app.drta_handles.draq_p=draq_p;
                app.drta_handles.draq_d=draq_d;
            end


            app.drta_handles.draq_p.dgordra=2;
            % sz_t_trial=size(draq_d.t_trial);
            % sz_samp=size(draq_d.samplesPerTrial);
            % if (sz_t_trial(2)<data.noTrials)||(sz_samp(2)<data.noTrials)
            %    app.drta_handles.draq_d.noTrials=min([sz_t_trial(2) sz_samp(2)]);
            % end
            %app.drta_handles.p.fid=fopen(app.drta_handles.p.fullName,'r');

            scaling = app.drta_handles.draq_p.scaling;
            offset = app.drta_handles.draq_p.offset;

            app.drta_handles.draq_p.no_spike_ch=16;
            app.drta_handles.draq_p.daq_gain=8;
            app.drta_handles.draq_p.prev_ylim(1:17)=4000;
            app.drta_handles.draq_p.no_chans=22;
            app.drta_handles.draq_p.acquire_display_start=0;
            app.drta_handles.draq_p.inp_max=10;
            % app.drta_handles.p.trialNo=2;
            % app.drta_handles.p.low_filter=1000;
            % app.drta_handles.p.high_filter=5000;
            % app.drta_handles.p.whichPlot=1;
            % app.drta_handles.p.lastTrace=-1;
            % app.drta_handles.p.exclude_secs=2;
            % app.drta_handles.p.set2p5SD=0;
            % app.drta_handles.p.setm2p5SD=0;
            % app.drta_handles.p.setnxSD=0;
            % app.drta_handles.p.nxSD=2.5;
            % app.drta_handles.p.showLicks=1;
            % app.drta_handles.p.exc_sn=0;
            % app.drta_handles.p.read_entire_file=0;
            % app.drta_handles.p.setThr=0;
            % app.drta_handles.p.thrToSet=0;


            if exist('drta_p')==1
                if isfield(drta_p,'doSubtract')
                    app.drta_handles.p.doSubtract=drta_p.doSubtract;
                    app.drta_handles.p.subtractCh=drta_p.subtractCh;
                else
                    app.drta_handles.p.doSubtract=0;
                    app.drta_handles.p.subtractCh=[18 18 18 18 18 18 18 18 18 18 18 18 18 18 18 18];
                end
            else
                app.drta_handles.p.doSubtract=0;
                app.drta_handles.p.subtractCh=[18 18 18 18 18 18 18 18 18 18 18 18 18 18 18 18];
            end

            for (ii=1:app.drta_handles.draq_p.no_spike_ch)
                try
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                catch
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                end

            end

            app.drta_handles.p.trial_ch_processed=ones(16,app.drta_handles.draq_d.noTrials);
            app.drta_handles.p.trial_allch_processed=ones(1,app.drta_handles.draq_d.noTrials);

            if (exist('drta_p')~=0)
                app.drta_handles.p.threshold=drta_p.threshold;

                if (isfield(drta_p,'ch_processed')~=0)
                    app.drta_handles.p.ch_processed=drta_p.ch_processed;
                end
                if (isfield(drta_p,'trial_ch_processed')~=0)
                    app.drta_handles.p.trial_ch_processed=drta_p.trial_ch_processed;
                end
                if (isfield(drta_p,'trial_allch_processed')~=0)
                    app.drta_handles.p.trial_allch_processed=drta_p.trial_allch_processed;
                end
                if (isfield(drta_p,'upper_limit')~=0)
                    app.drta_handles.p.upper_limit=drta_p.upper_limit;
                    app.drta_handles.p.lower_limit=drta_p.lower_limit;
                end
                if isfield(drta_p,'exc_sn')
                    app.drta_handles.p.exc_sn=drta_p.exc_sn;
                    %         app.drta_handles.p.exc_sn_thr=drta_p.exc_sn_thr;
                    %         app.drta_handles.p.exc_sn_ch=drta_p.exc_sn_ch;
                end

            end

            app.drta_handles.p.trialNo=1;

            %Read recordings
            if app.drta_handles.p.read_entire_file==1
                app.drta_handles.draq_d.data=drtaReadData(app.drta_handles);
            end

            %Find the threshold for the licks
            app.drta_handles.p.lick_th_frac=0.3;
            one_per=zeros(1,length(app.drta_handles.draq_d.t_trial));
            ninetynine_per=zeros(1,length(app.drta_handles.draq_d.t_trial));
            ii_from=floor((app.drta_handles.draq_p.acquire_display_start+app.drta_handles.p.start_display_time)...
                *app.drta_handles.draq_p.ActualRate+1);
            ii_to=floor((app.drta_handles.draq_p.acquire_display_start+app.drta_handles.p.start_display_time...
                +app.drta_handles.p.display_interval)*app.drta_handles.draq_p.ActualRate)-2000;
            ii=19; %These are licks

            old_trial=app.drta_handles.p.trialNo;
            for trialNo=1:length(app.drta_handles.draq_d.t_trial)-1
                app.drta_handles.p.trialNo=trialNo;

                data_this_trial=drtaGetTraceData(app.drta_handles);

                this_data=[];
                this_data=data_this_trial(:,ii);
                one_per(trialNo)=prctile(this_data(ii_from:ii_to),0.5);
                ninetynine_per(trialNo)=prctile(this_data(ii_from:ii_to),99.5);
            end
            app.drta_handles.p.trialNo=old_trial;
            app.drta_handles.p.exc_sn_thr=app.drta_handles.p.lick_th_frac*mean(ninetynine_per-one_per);

            app.drta_handles.p.lfp.maxLFP=4100;
            app.drta_handles.p.lfp.minLFP=10;

            % Update app.drta_handles structure
            guidata(hObject, app.drta_handles);


            %Open threshold window
            h=drtaThresholdSnips;
            app.drta_handles.w.drtaThresholdSnips=h;
            drtaUpdateAllapp.drta_handlespw(hObject,app.drta_handles);
            drtaThresholdSnips('updateapp.drta_handles',h,eventdata,app.drta_handles);

            %Open browse traces
            h=drtaBrowseTraces;
            app.drta_handles.w.drtaBrowseTraces=h;
            % Update app.drta_handles structure
            guidata(hObject, app.drta_handles);
            % Update app.drta_handles structure here and elsewhere
            drtaUpdateAllapp.drta_handlespw(hObject, app.drta_handles);
            drtaBrowseTraces('updateapp.drta_handles',h,eventdata,app.drta_handles);
            drtaBrowseTraces('updateBrowseTraces',h);


        end


        %         function drtaBrowseDraPush_Callback(app, handles)
        %
        %             h=drtaBrowseTraces;
        %             handles.w.drtaBrowseTraces=h;
        %             % Update handles structure
        %             guidata(hObject, handles);
        %             % Update handles structure here and elsewhere
        %             drtaUpdateAllHandlespw(app, handles);
        %             drtaBrowseTraces('updateHandles',h,eventdata,handles);
        %             drtaBrowseTraces('updateBrowseTraces',h);
        %         end

        %         function drtaThresholdPush_Callback(app, handles)
        %
        %             h=drtaThresholdSnips;
        %             handles.w.drtaThresholdSnips=h;
        %             %handles.p.trialNo=1;
        %             % Update handles structure here and elsewhere
        %             drtaUpdateAllHandlespw(app, handles);
        %             drtaThresholdSnips('updateHandles',h,eventdata,handles);
        %         end

        %         function trialNo = getTrialNo(~, handles)
        %
        %             trialNo=handles.p.trialNo;
        %         end
        %
        %         function actualTrialNo = setTrialNo(app, trialNo)
        %             % hObject    handle to drtaBrowseDraPush (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    structure with handles and user data (see GUIDATA)
        %
        %             handles=guidata(hObject);
        %
        %             sztrials=size(handles.draq_d.t_trial);
        %             actualTrialNo=trialNo;
        %             if (trialNo<1)
        %                 actualTrialNo=1;
        %                 handles.p.trialNo=1;
        %             end
        %
        %             if (trialNo>sztrials(2))
        %                 actualTrialNo=sztrials(2);
        %                 handles.p.trialNo=sztrials(2);
        %             end
        %
        %             if (trialNo>=1)&&(trialNo<=sztrials(2))
        %                 actualTrialNo=trialNo;
        %                 handles.p.trialNo=trialNo;
        %             end
        %
        %             % Update handles structure
        %             guidata(hObject, handles);
        %         end


        %         function drtaUpdateAllHandlespw(app,newhandles)
        %             % hObject    handle to drtaBrowseDraPush (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    structure with handles and user data (see GUIDATA)
        %
        %             handles=app.drta_handles;
        %             handles.p=newhandles.p;
        %             handles.draq_p=newhandles.draq_p;
        %             % Update handles structure
        %             app.drta_handles = handles;
        %
        %             if handles.w.drtaBrowseTraces~=0
        %                 hObject=handles.w.drtaBrowseTraces;
        % %                 handles=guidata(hObject);
        %                 handles.p=newhandles.p;
        %                 handles.w=newhandles.w;
        %                 handles.draq_p=newhandles.draq_p;
        %                 % Update the handles structure
        % %                 guidata(hObject, handles);
        %                 %drtaBrowseTraces('updateBrowseTraces',hObject);
        %             end
        %
        % %             handles=guidata(gcbo);
        %             if handles.w.drtaThresholdSnips~=0
        %                 hObject=handles.w.drtaThresholdSnips;
        % %                 handles=guidata(hObject);
        %                 handles.p=newhandles.p;
        %                 handles.w=newhandles.w;
        %                 handles.draq_p=newhandles.draq_p;
        %                 % Update the handles structure
        % %                 guidata(hObject, handles);
        % %                 drtaThresholdSnips('drtaThSnSetTrials',hObject,handles);
        %                 %drtaThresholdSnips('drtaThresholdAndPlot',hObject,handles);
        %             end

        %             app.drta_handles = handles;
        %         end
        function drtaBrTr3xSD_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaBrTr3xSD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of drtaBrTr3xSD

            handles.p.do_3xSD=get(hObject,'Value');
            % Update handles structure
            guidata(hObject, handles);
            updateBrowseTraces(app, hObject);
        end

        function drtaEditExCh_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaEditExCh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of drtaEditExCh as text
            %        str2double(get(hObject,'String')) returns contents of drtaEditExCh as a double
            handles.p.exc_sn_ch=str2num(get(hObject,'String'));
            %set(hObject,'String',num2str(handles.p.exc_sn_thr_9to16));

            if handles.p.exc_sn_ch>16
                handles.p.exc_sn_ch=16;
            end

            if handles.p.exc_sn_ch<1
                handles.p.exc_sn_ch=1;
            end


            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
            drtaPlotBrowseTraces(handles);
        end

        function drtaEditExCh_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to drtaEditExCh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function drtaEditExcSn_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaEditExcSn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of drtaEditExcSn as text
            %        str2double(get(hObject,'String')) returns contents of drtaEditExcSn as a double
            handles.p.exc_sn_thr=str2num(get(hObject,'String'));
            %set(hObject,'String',num2str(handles.p.display_interval));

            if ~isfield(handles.p,'exc_sn_ch')
                handles.p.exc_sn_ch=1;
                set(handles.drtaEditExCh,'String',num2str(handles.p.exc_sn_ch));
            end

            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
            drtaPlotBrowseTraces(handles);
        end

        function drtaEditExcSn_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to drtaEditExcSn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function up = getUpdatePreview(app, hObject)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            handles=guidata(hObject);
            up = handles.p.update_preview;
        end

        function ylim = getYlim(app, hObject)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            handles=guidata(hObject);
            ylim = handles.p.prev_ylim;
        end

        function setTrialsOutcome(app, handles, trialsOutcome)
            % hObject    handle to drtaBrowseDraPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            handlesb=handles.w.drtaBrowseTraces;
            try
                set(handlesb.trialOutcome,'String',trialsOutcome);
            catch
            end
        end

        function setUpdatePreview(app, hObject, up)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            handles=guidata(hObject);
            handles.p.update_preview=up;

            % Update the handles structure
            guidata(hObject, handles);
        end

        function updateBrowseTraces(app,NewHandles)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            handles=guidata(gcbo);

            drtaPlotBrowseTraces(handles);
        end

        function updateHandles(app, hObject, eventdata, handdrta)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            tic
            handles=guidata(hObject);
            handles.p=handdrta.p;
            handles.w=handdrta.w;
            handles.draq_p=handdrta.draq_p;
            handles.draq_d=handdrta.draq_d;
            handles.w.drtaBrowseTraces=hObject;
            %handles.p.which_channel=1;
            %handles.p.which_display=1;
            %handles.p.do_filter=1;
            handles.w.trialOutcome=handles.trialOutcome;
            % handles.p.doSubtract=0;
            % handles.p.low_filter=1000;
            % handles.p.high_filter=5000;
            % handles.p.subtractCh=[1 8 11 15];


            % Setup popup strings
            popstr={'all','1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Digital'};
            set(handles.drtaPSWhichChan,'String',popstr);
            set(handles.drtaWhichDisplay,'String',popstr);
            set(handles.drtaEditYRange,'String',num2str(handles.draq_p.prev_ylim(1)));
            set(handles.drtaTrialPush,'String',drta('getTrialNo',handles.w.drta));
            set(handles.drtaEditTimeStart,'String',num2str(handles.p.start_display_time));
            set(handles.drtaEditTimeInterval,'String',num2str(handles.p.display_interval));
            set(handles.drtaRadioFilter,'Value',handles.p.do_filter);
            set(handles.drtaLFPmax,'String',num2str(handles.p.lfp.maxLFP))
            set(handles.drtaLFPmin,'String',num2str(handles.p.lfp.minLFP))
            %set(handles.drtaBrTr3xSD,'Value',handles.p.do_3xSD);

            %Please note that in the old files there is no handles.p.doSubtract
            if ~isfield(handles.p,'doSubtract')
                handles.p.doSubtract=0;
                handles.p.subtractCh=cell(1,16);
            end

            set(handles.drtaDiff,'Value', handles.p.doSubtract);

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub1,'String',popstr);
            set(handles.drtaSub1,'Value',handles.p.subtractCh(1));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub2,'String',popstr);
            set(handles.drtaSub2,'Value',handles.p.subtractCh(2));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub3,'String',popstr);
            set(handles.drtaSub3,'Value',handles.p.subtractCh(3));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub4,'String',popstr);
            set(handles.drtaSub4,'Value',handles.p.subtractCh(4));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub5,'String',popstr);
            set(handles.drtaSub5,'Value',handles.p.subtractCh(5));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub6,'String',popstr);
            set(handles.drtaSub6,'Value',handles.p.subtractCh(6));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub7,'String',popstr);
            set(handles.drtaSub7,'Value',handles.p.subtractCh(7));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub8,'String',popstr);
            set(handles.drtaSub8,'Value',handles.p.subtractCh(8));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub9,'String',popstr);
            set(handles.drtaSub9,'Value',handles.p.subtractCh(9));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub10,'String',popstr);
            set(handles.drtaSub10,'Value',handles.p.subtractCh(10));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub11,'String',popstr);
            set(handles.drtaSub11,'Value',handles.p.subtractCh(11));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub12,'String',popstr);
            set(handles.drtaSub12,'Value',handles.p.subtractCh(12));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub13,'String',popstr);
            set(handles.drtaSub13,'Value',handles.p.subtractCh(13));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub14,'String',popstr);
            set(handles.drtaSub14,'Value',handles.p.subtractCh(14));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub15,'String',popstr);
            set(handles.drtaSub15,'Value',handles.p.subtractCh(15));

            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub16,'String',popstr);
            set(handles.drtaSub16,'Value',handles.p.subtractCh(16));

            popstr={'Raw','Wide 4-100','High Theta 6-14','Theta 2-14','Beta 15-36','Gamma1 35-65',...
                'Gamma2 65-95','Gamma 35-95','Spikes 500-5000','Spike var', 'digital'};
            set(handles.drtaRadioFilter,'String',popstr);
            set(handles.drtaRadioFilter,'Value',1);
            if isfield(handles.p,'exc_sn')
                set(handles.drtaRadioExclSn,'Value',handles.p.exc_sn);
            else
                handles.p.exc_sn=0;
            end

            handles.draq_p.auto_thr_sign=1;


            toc

            % Update the handles structure
            guidata(hObject, handles);
        end

        function PlotHandlesUpdate(app,handleOld)
            app.drta_handles = handleOld;
            disp("updated handles\n")


        end

        function ReadoutUpdate(app,textUpdate)
            TextPrint = textUpdate;
            OTsize = size(app.OutputText);
            if app.FlagAlpha == true
                app.OutputText{1,1} = append(app.OutputText{1,1},textUpdate);
                app.FlagAlpha = false;
            else
                app.OutputText{(OTsize(1)+1),1} = TextPrint;
            end
            app.ProgressReadout.Value = app.OutputText;
            pause(.1)
        end

        function trialNo = getTrialNo(app)
            handles=guidata(gcbo);
            trialNo=app.drta_handles.p.trialNo;
        end

        function actualTrialNo = setTrialNo(app,trialNo)
            sztrials=size(app.drta_handles.draq_d.t_trial);
            actualTrialNo=trialNo;
            if (trialNo<1)
                actualTrialNo=1;
                app.drta_handles.p.trialNo=1;
            end

            if (trialNo>sztrials(2))
                actualTrialNo=sztrials(2);
                app.drta_handles.p.trialNo=sztrials(2);
            end

            if (trialNo>=1)&&(trialNo<=sztrials(2))
                actualTrialNo=trialNo;
                app.drta_handles.p.trialNo=trialNo;
            end

        end
        
        function drtaUpdateAllHandlespw(app, newhandles)

            handles=guidata(gcbo);
            handles.p=newhandles.p;
            handles.draq_p=newhandles.draq_p;
            % Update handles structure
            guidata(gcbo, handles);

        end
    end

    methods (Access = private)


        function figChange(app)
            %{
            This function is used to adjsut the figured displayed based on
            the user selections from the dropdown menus
            %}
            clf(app.drta_handles.s_handle,"reset")
            clear app.drta_handles.s_handle
            PSel = [32,34;30,32;28,30;26,28; ...
                24,26;22,24;20,22;18,20; ...
                16,18;14,16;12,14;10,12; ...
                8,10;6,8;4,6;2,4];
            %             Wct = 1;

            for ii = 1:16

                if app.drta_handles.p.subtractCH{1,ii}{:} == "Tet"
                    app.drta_handles.p.subtractCh{1,ii}{:} = 17;
                elseif app.drta_handles.p.subtractCH{1,ii}{:} == "Avg"
                    app.drta_handles.p.subtractCh{1,ii}{:} = 18;
                elseif app.drta_handles.p.subtractCH{1,ii}{:} == "No"
                    app.drta_handles.p.subtractCh{1,ii}{:} = 19;
                else

                end
                if ii < 16
                    SameAsNext = app.drta_handles.p.subtractCh{1,ii}{:} == app.drta_handles.p.subtractCh{1,ii+1}{:};
                elseif ii == 16
                    SameAsNext = app.drta_handles.p.subtractCh{1,ii}{:} == app.drta_handles.p.subtractCh{1,ii-1}{:};
                end
                if SameAsNext == ture
                    if ~isempty(stPlc)
                        stPlc = ii;
                    end
                else
                    if ~isempty(stPlc)
                        stPlc = ii;
                    end
                    app.drta_handles.plot.s_handle(ii) = uiaxes(drta_ax);
                    app.drta_handles.plot.s_handle(ii).Layout.Row = [stPlc PSel(ii,2)];
                    app.drta_handles.plot.s_handle(ii).Layout.Column = [2 9];
                    app.drta_handles.plot.s_handle(ii).XTickLabels = {};
                    app.drta_handles.plot.s_handle(ii).YTickLabels = {};
                    app.drta_handles.plot.s_handle(ii).Title.Visible = "off";
                    app.drta_handles.plot.s_handle(ii).Box = 'on';
                    currentInner = app.drta_handles.plot.s_handle(ii).InnerPosition;
                    if ii == 1
                        % lowermost plot is taller due to x-tic labels
                        app.drta_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+10, ...
                            currentInner(3), 40];
                    else
                        app.drta_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+3, ...
                            currentInner(3), 38];
                    end
                end
                stPlc = [];


            end
            drtaGenerateEvents(app.drta_handles)
        end

        function figCollect(app)
            app.drta_handles.p.subtractCh = [];
            app.drta_handles.p.subtractCh = { ...
                app.Sub_01.Value, ...
                app.Sub_02.Value, ...
                app.Sub_03.Value, ...
                app.Sub_04.Value, ...
                app.Sub_05.Value, ...
                app.Sub_06.Value, ...
                app.Sub_07.Value, ...
                app.Sub_08.Value, ...
                app.Sub_09.Value, ...
                app.Sub_10.Value, ...
                app.Sub_11.Value, ...
                app.Sub_12.Value, ...
                app.Sub_13.Value, ...
                app.Sub_14.Value, ...
                app.Sub_15.Value, ...
                app.Sub_16.Value, ...
                };
            figChange(app)
        end


        function ClearAllConvertFileds(app)
            app.FSinput0.Value = "Select a new file";
            app.FSinput1.Value = "Choose a new file";
            app.FSinput2.Value = [];
            app.FSinput3.Value = "1000";
            app.FSinput5.Value = "4";
            app.FSinput6.Value = [];
            app.FSinput7.Value = [];
            app.TSinput0.Value = [];
            app.TSinput1.Value = [];
            app.TSinput2.Value = [];
            app.TSinput3.Value = [];
            app.TSinput4.Value = [];
            app.TSinput5.Value = [];
            app.MSinput0.Value = [];
            app.MSinput1.Value = [];
            app.MSinput2.Value = [];
            app.MSinput3.Value = [];
            app.MSinput4.Value = [];
            app.MSinput5.Value = [];
            app.MSinput6.Value = [];
            app.MSinput7.Value = [];
        end
        
        function LoadAllConvertFileds(app)
            
            filename = 'NWB_Settings.xlsx';
            app.FSinput0.Value = readmatrix(filename,'Sheet',1,'Range','B3');
            FSimputTemp1 = readmatrix(filename,'Sheet',1,'Range','B4');
            app.FSinput1.Value = str(FSimputTemp1);
            app.FSinput2.Value = readmatrix(filename,'Sheet',1,'Range','B5');
            app.FSinput3.Value = readmatrix(filename,'Sheet',1,'Range','B6');
            app.FSinput4 = readmatrix(filename,'Sheet',1,'Range','B7');
            app.FSinput5.Value = readmatrix(filename,'Sheet',1,'Range','B8');
            app.FSinput6.Value = readmatrix(filename,'Sheet',1,'Range','B9');
            app.FSinput7.Value = readmatrix(filename,'Sheet',1,'Range','B10');
            FSinputtemp8 = readmatrix(filename,'Sheet',1,'Range','B11');
            if FSinputtemp8 == "TRUE"
                app.FSinput4yes.Value = "true";
            elseif FSinputtemp8 == "FALSE"
                app.FSinput4no.Value = "true";
            end
            % Session Start Time settings

            FSinputtemp9 = readmatrix(filename,'Sheet',1,'Range','B13');
            if FSinputtemp9 == "TRUE"
                app.FSinput9yes.Value = "true";
            elseif FSinputtemp9 == "FALSE"
                app.FSinput9no.Value = "true";
            end
            app.TSinput0.Value = readmatrix(filename,'Sheet',1,'Range','B14');
            app.TSinput1.Value = readmatrix(filename,'Sheet',1,'Range','B15');
            app.TSinput2.Value = readmatrix(filename,'Sheet',1,'Range','B16');
            app.TSinput3.Value = readmatrix(filename,'Sheet',1,'Range','B17');
            app.TSinput4.Value = readmatrix(filename,'Sheet',1,'Range','B18');
            app.TSinput5.Value = readmatrix(filename,'Sheet',1,'Range','B19');
            % Subject Metadata Settings
            TSinputtemp10 = readmatrix(filename,'Sheet',1,'Range','B21');
            if TSinputtemp10 == "TRUE"
                app.FSinput10yes.Value = "true";
            elseif TSinputtemp10 == "FALSE"
                app.FSinput10no.Value = "true";
            end
            app.MSinput0.Value = readmatrix(filename,'Sheet',1,'Range','B22');
            app.MSinput1.Value = readmatrix(filename,'Sheet',1,'Range','B23');
            app.MSinput2.Value = readmatrix(filename,'Sheet',1,'Range','B24');
            app.MSinput3.Value = readmatrix(filename,'Sheet',1,'Range','B25');
            app.MSinput4.Value = readmatrix(filename,'Sheet',1,'Range','B26');
            app.MSinput5.Value = readmatrix(filename,'Sheet',1,'Range','B27');
            app.MSinput6.Value = readmatrix(filename,'Sheet',1,'Range','B28');
            app.MSinput7.Value = readmatrix(filename,'Sheet',1,'Range','B29');
            MSinputtemp8 = readmatrix(filename,'Sheet',1,'Range','B30');
            if MSinputtemp8 == "TRUE"
                app.MSinput8yes.Value = "true";
            elseif MSinputtemp8 == "False"
                app.MSinput8no.Value = "true";
            end
            app.MSinput9.Value = readmatrix(filename,'Sheet',1,'Range','B31');
            app.MSinput10.Value = readmatrix(filename,'Sheet',1,'Range','B32');
            app.MSinput11.Value = readmatrix(filename,'Sheet',1,'Range','B33');

        end
        
        function WritingAllConvertFields(app)
            filename = 'NWB_Settings.xlsx';
            writematrix(app.FSinput0.Value,filename,'Sheet',1,'Range','B3')
            writematrix(char(app.FSinput1.Value),filename,'Sheet',1,'Range','B4')
            writematrix(app.FSinput2.Value,filename,'Sheet',1,'Range','B5')
            writematrix(app.FSinput3.Value,filename,'Sheet',1,'Range','B6')
            if app.FSinput4yes.Value == 1
                FSinput4 = 'TRUE';
            else
                FSinput4 = 'FALSE';
            end
            writematrix(FSinput4,filename,'Sheet',1,'Range','B7')

            writematrix(app.FSinput5.Value,filename,'Sheet',1,'Range','B8')
            writematrix(app.FSinput6.Value,filename,'Sheet',1,'Range','B9')
            writematrix(app.FSinput7.Value,filename,'Sheet',1,'Range','B10')
            if app.FSinput8yes.Value == 1
                writematrix('TRUE',filename,'Sheet',1,'Range','B11')
            else
                writematrix('FALSE',filename,'Sheet',1,'Range','B11')
            end
%             app.File_location = app.FSinput11.Value;
            % Session Start Time settings
            if app.FSinput9yes.Value == 1
                writematrix('TRUE',filename,'Sheet',1,'Range','B13')
                writematrix(app.TSinput0.Value,filename,'Sheet',1,'Range','B14')
                writematrix(app.TSinput1.Value,filename,'Sheet',1,'Range','B15')
                writematrix(app.TSinput2.Value,filename,'Sheet',1,'Range','B16')
                writematrix(app.TSinput3.Value,filename,'Sheet',1,'Range','B17')
                writematrix(app.TSinput4.Value,filename,'Sheet',1,'Range','B18')
                writematrix(app.TSinput5.Value,filename,'Sheet',1,'Range','B19')
            else
                writematrix('FALSE',filename,'Sheet',1,'Range','B13')
            end
            % Subject Metadata Settings
            if app.FSinput10yes.Value == 1
                writematrix('TRUE',filename,'Sheet',1,'Range','B21')
                writematrix(app.MSinput0.Value,filename,'Sheet',1,'Range','B22')
                writematrix(app.MSinput1.Value,filename,'Sheet',1,'Range','B23')
                writematrix(app.MSinput2.Value,filename,'Sheet',1,'Range','B24')
                writematrix(app.MSinput3.Value,filename,'Sheet',1,'Range','B25')
                writematrix(app.MSinput4.Value,filename,'Sheet',1,'Range','B26')
                writematrix(app.MSinput5.Value,filename,'Sheet',1,'Range','B27')
                writematrix(app.MSinput6.Value,filename,'Sheet',1,'Range','B28')
                writematrix(app.MSinput7.Value,filename,'Sheet',1,'Range','B29')
                if app.MSinput8yes.Value == 1
                    writematrix('TRUE',filename,'Sheet',1,'Range','B30')
                    writematrix(app.MSinput9.Value,filename,'Sheet',1,'Range','B31')
                    writematrix(app.MSinput10.Value,filename,'Sheet',1,'Range','B32')
                    writematrix(app.MSinput11.Value,filename,'Sheet',1,'Range','B33')
                    
                else
                    writematrix('FALSE',filename,'Sheet',1,'Range','B30')
                end
            else
                writematrix('FALSE',filename,'Sheet',1,'Range','B21')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pyenv("ExecutionMode","OutOfProcess")
            app.File_table = table('size',[50,1],'VariableTypes',"cell");
            app.File_amt = 0;
            app.File_totCount = 0;
            AppTypeSelectionChanged(app);
            drtaProcessVersionSelectionChanged(app);
            % Choose default command line output for drta
            %load('C:\Awake Data\drta_preferences.mat');

            app.drta_handles.output = app;
            app.drta_handles.w.drta = app;
            app.drta_handles.time_before_FV = 1;
            app.drta_handles.w.drtaBrowseTraces=0;
            app.drta_handles.w.drtaThresholdSnips=0;
            %
            app.GridLayout.Visible = 'off';
            app.GridLayout2.Visible = 'off';
            app.GridLayout3.Visible = 'off';

            %{
            Setting up initial parameters/conditions for drta_handles.p
            %}
            app.drta_handles.p = drtaSetPreferences();
            app.drta_handles.p.trialNo = 1;
            app.drta_handles.p.low_filter = 1000;
            app.drta_handles.p.high_filter = 5000;
            app.drta_handles.p.whichPlot = 1;
            app.drta_handles.p.lastTrace = -1;
            app.drta_handles.p.exclude_secs = 2;
            app.drta_handles.p.set2p5SD = 0;
            app.drta_handles.p.setm2p5SD = 0;
            app.drta_handles.p.setnxSD = 0;
            app.drta_handles.p.nxSD = 2.5;
            app.drta_handles.p.showLicks = 1;
            app.drta_handles.p.exc_sn = 0;
            app.drta_handles.p.read_entire_file = 0;
            app.drta_handles.p.setThr = 0;
            app.drta_handles.p.thrToSet = 0;
            if ~isfield(app.drta_handles.p,'doSubtract')
                app.drta_handles.p.doSubtract = 0;
                app.drta_handles.p.subtractCh = cell(1,16);
            end
            app.drta_handles.p.threexsdexists(1:16) = 0;

            %             app.drta_handles=guidata(hObject);
            %             app.drta_handles.p=handdrta.p;
            %             app.drta_handles.w=handdrta.w;
            %             app.drta_handles.draq_p=handdrta.draq_p;
            %             app.drta_handles.draq_d=handdrta.draq_d;
            %             %app.drta_handles.p.which_channel=1;
            %             %app.drta_handles.p.which_display=1;
            %             %app.drta_handles.p.do_filter=1;
            %             app.drta_handles.w.trialOutcome=handles.trialOutcome;
            %             % app.drta_handles.p.doSubtract=0;
            %             % app.drta_handles.p.low_filter=1000;
            %             % app.drta_handles.p.high_filter=5000;
            %             % app.drta_handles.p.subtractCh=[1 8 11 15];

            %Please note that in the old files there is no handles.p.doSubtract


            %             if isfield(app.drta_handles.p,'exc_sn')
            %                 set(app.drta_handles.drtaRadioExclSn,'Value',app.drta_handles.p.exc_sn);
            %             else
            %                 app.drta_handles.p.exc_sn=0;
            %             end
            %
            app.drta_handles.draq_p.auto_thr_sign=1;


            %{
            Loads the locations for each plot
            %}
            drta_ax = app.GridLayoutTraces;
            for ii = 1:16
                app.drta_handles.plot.s_handle(ii)= uiaxes(drta_ax);
                PSel = [32,34;30,32;28,30;26,28; ...
                    24,26;22,24;20,22;18,20; ...
                    16,18;14,16;12,14;10,12; ...
                    8,10;6,8;4,6;2,4];
                app.drta_handles.plot.s_handle(ii).Layout.Row = [PSel(ii,1) PSel(ii,2)];
                app.drta_handles.plot.s_handle(ii).Layout.Column = [2 9];
                app.drta_handles.plot.s_handle(ii).XTickLabels = {};
                app.drta_handles.plot.s_handle(ii).YTickLabels = {};
                app.drta_handles.plot.s_handle(ii).Title.Visible = "off";
                app.drta_handles.plot.s_handle(ii).Box = 'on';
                currentInner = app.drta_handles.plot.s_handle(ii).InnerPosition;
                if ii == 1
                    % lowermost plot is taller due to x-tic labels
                    app.drta_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+10, ...
                        currentInner(3), 40];
                else
                    app.drta_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+3, ...
                        currentInner(3), 38];
                end
            end

        end

        % Value changed function: Sub_1, Sub_10, Sub_11, Sub_12, Sub_13, 
        % ...and 11 other components
        function SubValueChanged(app, event)
            figCollect(app)
        end

        % Value changed function: Yrange_amt
        function Yrange_amtValueChanged(app, event)
            %{
            This function works as intended based on the orignal guide GUI
            %}
            y_range = app.Yrange_amt.Value;
            %             v_range=y_range* app.drta_handles.draq_p.pre_gain* app.drta_handles.draq_p.daq_gain/1000000;


            if (app.drta_handles.p.which_channel==1)
                %Change for all channels
                for ii=1:app.drta_handles.draq_p.no_spike_ch
                    %         if v_range>handles.draq_p.inp_max
                    %             handles.draq_p.prev_ylim(ii)=1000000*handles.draq_p.inp_max/(handles.draq_p.pre_gain*handles.draq_p.daq_gain);
                    %         else
                    app.drta_handles.plot.s_handles(ii)=y_range;
                    app.drta_handles.draq_p.prev_ylim(ii)=y_range;
                    %         end4
                end
            else
                ii=app.drta_handles.p.which_channel-1;
                %     if v_range>handles.draq_p.inp_max
                %         handles.draq_p.prev_ylim(ii)=1000000*handles.draq_p.inp_max(2)/(handles.draq_p.pre_gain*handles.draq_p.daq_gain);
                %     else
                app.drta_handles.plot.s_handles(ii)=y_range;
                app.drta_handles.draq_p.prev_ylim(ii)=y_range;
                %     end
            end
            %             drta(app.drta_handles.w.drta,'drtaUpdateAllHandlespw',app.drta_handles)
            drtaPlotBrowseTraces(app.drta_handles.w.drta, ...
                app.drta_handles.w.drtaBrowseTraces, ...
                app.drta_handles);
        end

        % Value changed function: Yrange_DropDown
        function Yrange_DropDownValueChanged(app, event)
            app.drta_handles.p.which_channel = app.Yrange_DropDown.Value;

        end

        % Value changed function: Disp_DropDown
        function Disp_DropDownValueChanged(app, event)
            app.drta_handles.p.which_display = app.Disp_DropDown.Value;

            if app.drta_handles.p.which_display==1
                for ii = 1:app.drta_handles.draq_p.no_spike_ch
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                end
            else
                ii = app.drta_handles.p.which_display-1;
                app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(app.drta_handles.draq_p.no_spike_ch-0.5)+0.92*0.85/handles.draq_p.no_spike_ch;
                app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(1-0.5);
            end
        end

        % Value changed function: Interval_amt
        function Interval_amtValueChanged(app, event)
            time_interval = app.Interval_amt.Value;

            if(app.drta_handles.p.start_display_time+time_interval)>app.drta_handles.draq_p.sec_per_trigger
                time_interval= app.drta_handles.draq_p.sec_per_trigger-app.drta_handles.p.start_display_time;
            end

            app.drta_handles.p.display_interval=time_interval;
            app.Interval_amt.Value = app.drta_handles.p.display_interval;
            %             set(hObject,'String',num2str(handles.p.display_interval));

            %             % Update all handles.p structures
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Button pushed function: Tnum_Larrow
        function Tnum_LarrowButtonPushed(app, event)
            newTrial= app.drta_handles.p.trialNo-1;
            app.drta_handles.p.trialNo = setTrialNo(app, newTrial);
            %             app.drta_handles.p.trialNo=drta('setTrialNo',app.drta_handles.w.drta,newTrial);
            app.Tnum_amt.Value = app.drta_handles.p.trialNo;
            %             set(app.drta_handles.drtaTrialPush,'String', num2str(app.drta_handles.p.trialNo));
            %updateBrowseTraces(hObject);

            %             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drta_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drta_handles);
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Button pushed function: Tnum_Rarrow
        function Tnum_RarrowButtonPushed(app, event)
            newTrial=app.drta_handles.p.trialNo+1;
            app.drta_handles.p.trialNo = setTrialNo(app, newTrial);
            %             app.drta_handles.p.trialNo=drta('setTrialNo',app.drta_handles.w.drta,newTrial);
            app.Tnum_amt.Value = app.drta_handles.p.trialNo;
            %             set(app.drta_handles.drtaTrialPush,'String', num2str(app.drta_handles.p.trialNo));
            %updateBrowseTraces(hObject);

            %             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drta_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drta_handles);
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Value changed function: Tnum_amt
        function Tnum_amtValueChanged(app, event)
            app.drta_handles.p.trialNo = app.Tnum_amt.Value;
            app.drta_handles.p.trialNo = setTrialNo(app, app.drta_handles.p.trialNo);
            %             app.drta_handles.p.trialNo=drta('setTrialNo',app.drta_handles.w.drta, app.drta_handles.p.trialNo);
            app.Tnum_amt.Value = app.drta_handles.p.trialNo;
            %             set(app.drta_handles.drtaTrialPush,'String',num2str(app.drta_handles.p.trialNo));

            %             % Update all handles.p.structures
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            PlotHandlesUpdate(app,app.drta_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drta_handles);
            % drtaPlotBrowseTraces(app.drta_handles);
        end

        % Button pushed function: DTS_Larrow
        function DTS_LarrowButtonPushed(app, event)
            if (app.drta_handles.p.start_display_time-app.drta_handles.p.display_interval)>app.drta_handles.draq_p.acquire_display_start
                app.drta_handles.p.start_display_time= app.drta_handles.p.start_display_time-app.drta_handles.p.display_interval;
            end
            app.DTS_amt = app.drta_handles.drtaEditTimeStart;
            %             set(app.drta_handles.drtaEditTimeStart,'String',num2str(handles.p.start_display_time));

            %             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drta_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drta_handles);
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Button pushed function: DTS_Rarrow
        function DTS_RarrowButtonPushed(app, event)
            if (app.drta_handles.p.start_display_time+app.drta_handles.p.display_interval)>app.drta_handles.draq_p.acquire_display_start
                app.drta_handles.p.start_display_time= app.drta_handles.p.start_display_time+app.drta_handles.p.display_interval;
            end
            app.DTS_amt = app.drta_handles.drtaEditTimeStart;
            %             set(app.drta_handles.drtaEditTimeStart,'String',num2str(handles.p.start_display_time));

            %             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drta_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drta_handles);
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Value changed function: DTS_amt
        function DTS_amtValueChanged(app, event)
            start_time = app.DTS_amt.Value;
            if start_time<app.drta_handles.draq_p.acquire_display_start
                start_time= app.drta_handles.draq_p.acquire_display_start;
            end

            if (start_time+app.drta_handles.p.display_interval)>app.drta_handles.draq_p.sec_per_trigger
                start_time= app.drta_handles.draq_p.sec_per_trigger-app.drta_handles.p.display_interval;
            end
            app.drta_handles.p.start_display_time=start_time;
            app.DTS_amt.Value = app.drta_handles.p.start_display_time;

            %             set(hObject,'String',num2str(handles.p.start_display_time));

            %             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drta_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drta_handles);
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Value changed function: MClust_Button
        function MClust_ButtonValueChanged(app, event)
            drtaGenerateMClust(app.drta_handles);
        end

        % Value changed function: Licks_CheckBox
        function Licks_CheckBoxValueChanged(app, event)
            app.drta_handles.p.exc_sn = app.Licks_CheckBox.Value;
            %             handles.p.exc_sn=get(hObject,'Value');
            %set(hObject,'String',num2str(handles.p.display_interval));

            %             % Update all handles.p structures
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Value changed function: min_amt
        function min_amtValueChanged(app, event)
            app.drta_handles.p.lfp.minLFP = app.min_amt.Value;

            % Update all handles.p structures
            PlotHandlesUpdate(app,app.drta_handles);
        end

        % Value changed function: max_amt
        function max_amtValueChanged(app, event)
            app.drta_handles.p.lfp.maxLFP = app.max_amt.Value;

            %             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drta_handles);
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
        end

        % Value changed function: thr_amt
        function thr_amtValueChanged(app, event)
            app.drta_handles.p.thrToSet = app.thr_amt.Value;
            app.drta_handles.p.setThr=1;

            %             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drta_handles);
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
        end

        % Value changed function: nxSD_amt
        function nxSD_amtValueChanged(app, event)
            app.drta_handles.p.nxSD = app.nxSD_amt.Value;
            app.drta_handles.p.setnxSD=1;

            % Update handles structure
            drtaPlotBrowseTraces(app.drta_Main, app, app.drta_handles);
            app.drta_handles.p.setnxSD=0;
            PlotHandlesUpdate(app,app.drta_handles);
        end

        % Value changed function: Choice_DropDown
        function Choice_DropDownValueChanged(app, event)
            DropChoice = app.Choice_DropDown.Value;

            switch DropChoice
                case 'Raw'
                    app.drta_handles.p.whichPlot = 1;
                case 'Wide 4-100'
                    app.drta_handles.p.whichPlot = 2;
                case 'High Theta 6-14'
                    app.drta_handles.p.whichPlot = 3;
                case 'Theta 2-14'
                    app.drta_handles.p.whichPlot = 4;
                case 'Beta 15-36'
                    app.drta_handles.p.whichPlot = 5;
                case 'Gamma1 35-65'
                    app.drta_handles.p.whichPlot = 6;
                case 'Gamma2 65-95'
                    app.drta_handles.p.whichPlot = 7;
                case 'Gamma 35-95'
                    app.drta_handles.p.whichPlot = 8;
                case 'Spikes 500-5000'
                    app.drta_handles.p.whichPlot = 9;
                case 'Spike var digital'
                    app.drta_handles.p.whichPlot = 10;
            end

            % Update handles structure
            drtaPlotBrowseTraces(app.drta_handles.w.drta, ...
                app.drta_handles.w.drtaBrowseTraces, ...
                app.drta_handles);
        end

        % Value changed function: ProtocolTypeDropDown, type_DropDown
        function type_DropDownValueChanged(app, event)
            which_c_program = ...
                app.type_DropDown.Value;
            switch which_c_program
                case 'dropcnsampler'
                    app.drta_handles.p.which_c_program = 1;
                case 'dropcspm'
                    app.drta_handles.p.which_c_program = 2;
                case 'background'
                    app.drta_handles.p.which_c_program = 3;
                case 'spmult'
                    app.drta_handles.p.which_c_program = 4;
                case 'mspy'
                    app.drta_handles.p.which_c_program = 5;
                case 'osampler'
                    app.drta_handles.p.which_c_program = 6;
                case 'spm2mult'
                    app.drta_handles.p.which_c_program = 7;
                case 'lighton1'
                    app.drta_handles.p.which_c_program = 8;
                case 'lighton5'
                    app.drta_handles.p.which_c_program = 9;
                case 'dropcspm_conc'
                    app.drta_handles.p.which_c_program = 10;
                case 'Laser-triggered'
                    app.drta_handles.p.which_c_program = 11;
                case 'Laser-Merouann'
                    app.drta_handles.p.which_c_program = 12;
                case 'dropcspm_hf'
                    app.drta_handles.p.which_c_program = 13;
                case 'Working_memory'
                    app.drta_handles.p.which_c_program = 14;
                case 'Continuous'
                    app.drta_handles.p.which_c_program = 15;
            end
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to drtaThrSaveMat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            testb = app.SaveButton.Value;
            disp(testb)
            if (isfield(app.drta_handles.draq_d,'snip_samp'))
                try
                    app.drta_handles = rmfield(app.drta_handles.draq_d,'snip_samp');
                catch
                end

                try
                    app.drta_handles = rmfield(app.drta_handles.draq_d,'snip_index');
                catch
                end

                try
                    app.drta_handles = rmfield(app.drta_handles.draq_d,'snips');
                catch
                end
            end

            if (isfield(app.drta_handles.draq_d,'noEvents'))
                app.drta_handles = rmfield(app.drta_handles.draq_d,'noEvents');
                app.drta_handles = rmfield(app.drta_handles.draq_d,'nEvPerType');
                app.drta_handles = rmfield(app.drta_handles.draq_d,'nEventTypes');
                app.drta_handles = rmfield(app.drta_handles.draq_d,'eventlabels');

                try
                    app.drta_handles = rmfield(handles.draq_d,'events');
                    app.drta_handles = rmfield(handles.draq_d,'eventType');
                catch
                end


                try
                    app.drta_handles = rmfield(handles.draq_d,'blocks');
                catch
                end
            end
            type_DropDownValueChanged(app)
            drtaGenerateEvents(app.drta_handles);
            app.SaveButton.Value = 0;
        end

        % Value changed function: Load_Button
        function Load_ButtonValueChanged(app, event)
            %{
            Loading the file that the user selected and opening the visual
            GUI for user interaction.
            %}
            app.FlagAlpha = true;
            protocolPick = app.type_DropDown.Value;
            switch protocolPick
                case 'dropcspm'
                    app.drta_handles.p.which_protocol = 1;
                case 'laser (Ming)'
                    app.drta_handles.p.which_protocol = 2;
                case 'dropcnsampler'
                    app.drta_handles.p.which_protocol = 3;
                case 'laser (Merouann)'
                    app.drta_handles.p.which_protocol = 4;
                case 'dropc_conc'
                    app.drta_handles.p.which_protocol = 5;
                case 'dropcspm_hf'
                    app.drta_handles.p.which_protocol = 6;
                case 'continuous'
                    app.drta_handles.p.which_protocol = 7;
                case 'working_memory'
                    app.drta_handles.p.which_protocol = 8;
            end
            app.drta_handles.trial_duration=app.trial_amt.Value;
            app.drta_handles.pre_dt=app.dt_amt.Value;
            app.dt_amt.Enable = false;
            app.trial_amt.Enable = false;
            app.type_DropDown.Enable = false;
            app.OutputText{1,1} = 'Loading ';

            app.ReadoutUpdate(protocolPick);
            if strcmp(app.drta_handles.p.FileName(end-2:end),'rhd')
                open_rhd_Callback(app);
            elseif strcmp(app.drta_handles.p.FileName(end-2:end),'.dg')
                drtaOpenDG_Callback(app);
            end
            switch app.drtaProcessVersion.SelectedObject.Text
                case "drta 2.0"
                    %Open browse traces
                    app.h=drtaNWB_BrowseTraces;
%                     drtaBrowseTraces('updateHandles',app.h,[],app.drta_handles);
                    drtaNWB_BrowseTraces('updateBrowseTraces',app.h);
                case "drta 3.0"
                    %{
                    Plotting the initial traces using default values
                    %}
                    drtaNWB_PlotBrowseTracesNew(app, app.drta_handles);


            end
        end

        % Value changed function: SelectFile_Button
        function SelectFile_ButtonValueChanged(app, event)
            %{
            Calling a user interface to select the file that needs to be
            opened. The output is the file name and path.
            %}
            [FileName,PathName] = uigetfile({'*.rhd';'*.dg'},'Select rhd or dg file to open');
            app.drta_handles.p.fullName=[PathName,FileName];
            app.drta_handles.p.FileName=FileName;
            app.drta_handles.p.PathName=PathName;
            app.file_name.Value = app.drta_handles.p.FileName;

        end

        % Selection changed function: AppType
        function AppTypeSelectionChanged(app, event)
            selectedButton = app.AppType.SelectedObject;
            if selectedButton.Text == "rhd to NWB"
                app.drtaProcessVersion.Visible = "off";
                app.SelectFile_Button.Visible = "off";
                app.file_name.Visible = "off";
                app.type_DropDown_2.Visible = "off";
                app.dt_Label.Visible = "off";
                app.dt_amt.Visible = "off";
                app.Trial_Label.Visible = "off";
                app.trial_amt.Visible = "off";
                app.Load_Button.Visible = "off";
                app.OutputLocationLabel.Visible = "on";
                app.NWBconvert.Visible = "on";
                app.ConvertrhdFileButton.Visible = "on";
                app.ConvertrhdFileButton.Enable = "off";
                app.OutputFileName.Visible = "on";
                app.NWBfilenameLabel.Visible = "on";
                app.IntanfilenameLabel.Visible = "on";
                app.InputFileName.Visible = "on";
                app.GridLayoutSave.Visible = "off";
                app.ChooseOutputLocationButton.Visible = "on";
                app.LocationForOutput.Visible = "on";
                NWBconvertSelectionChanged(app)
                app.GridLayoutTraces.Visible = "off";
                app.GridLayout.Visible = "on";
                app.GridLayout2.Visible = "on";
                app.GridLayout3.Visible = "on";
                app.ChooseOutputLocationButton.Visible = "off";
            elseif selectedButton.Text == "View and process rhd file"
                app.NWBconvert.Visible = "off";
                app.ConvertrhdFileButton.Visible = "off";
                app.OutputFileName.Visible = "off";
                app.NWBfilenameLabel.Visible = "off";
                app.IntanfilenameLabel.Visible = "off";
                app.InputFileName.Visible = "off";
                app.CreateFileforConversionButton.Visible = "off";
                app.NextFile.Visible = "off";
                app.LastFile.Visible = "off";
                app.CurrentFileNumber.Visible = "off";
                app.FIlenumberProcessLabel.Visible = "off";
                app.NumberOfFileLabel.Visible = "off";
                app.FileNumber.Visible = "off";
                app.ChooseOutputLocationButton.Visible = "off";
                app.LocationForOutput.Visible = "off";
                app.ChooseOutputLocationButton.Visible = "on";
                app.LocationForOutput.Visible = "on";
                app.drtaProcessVersion.Visible = "on";
                app.SelectFile_Button.Visible = "on";
                app.file_name.Visible = "on";
                app.type_DropDown_2.Visible = "on";
                app.dt_Label.Visible = "on";
                app.dt_amt.Visible = "on";
                app.Trial_Label.Visible = "on";
                app.trial_amt.Visible = "on";
                app.Load_Button.Visible = "on";
                app.GridLayoutSave.Visible = "on";
                app.GridLayoutTraces.Visible = "off";
                app.GridLayout.Visible = "off";
                app.GridLayout2.Visible = "off";
                app.GridLayout3.Visible = "off";
                app.OutputLocationLabel.Visible = "off";
            end
        end

        % Selection changed function: NWBconvert
        function NWBconvertSelectionChanged(app, event)
            selectedButton = app.NWBconvert.SelectedObject;
            
            if selectedButton.Text == "Batch of Files"
                app.NextFile.Visible = "on";
                app.LastFile.Visible = "on";
                app.CurrentFileNumber.Visible = "on";
                app.FIlenumberProcessLabel.Visible = "on";
                app.NumberOfFileLabel.Visible = "on";
                app.FileNumber.Visible = "on";
                app.CreateFileforConversionButton.Visible = "on";
            else
                app.NextFile.Visible = "off";
                app.LastFile.Visible = "off";
                app.CurrentFileNumber.Visible = "off";
                app.FIlenumberProcessLabel.Visible = "off";
                app.NumberOfFileLabel.Visible = "off";
                app.FileNumber.Visible = "off";
                app.OutputLocationLabel.Visible = "on";
                app.CreateFileforConversionButton.Visible = "off";
            end
        end

        % Value changed function: ConvertrhdFileButton
        function ConvertrhdFileButtonValueChanged(app, event)
            if app.SingleFileButton.Value == 1
                % this spot needs to have moving the files around
                C_location = pwd;
                cd("BlankSheet");
                copyfile("NWB_Settings.xlsx",C_location)
                cd(app.File_rhdlocation)
                movefile(app.File_rhdname,C_location)
                cd(C_location)
                WritingAllConvertFields(app)
                app.File_amt = 1;
                
            else
                C_location = pwd;
                
            end
            for fm = 1:app.File_amt
                if app.SingleFileButton.Value == 1
                    Name_File = py.numpy.str_(app.File_rhdname);
                    Name_Output = py.numpy.str_(app.NWBFileName);
                    Name_dir = py.numpy.str_(pwd);
                else
                    cd(app.File_table{fm,1}{:,:})
                    rhd_file = dir("*.rhd");
                    excel_file = dir("*.xlsx");
                    movefile(rhd_file.name,C_location);
                    movefile(excel_file.name,C_location);
                    cd(C_location)
                    filename = 'NWB_Settings.xlsx';
                    Input_file = readmatrix(filename,'Sheet',1,'Range','B3:B3','OutputType','string');
                    Output_file = readmatrix(filename,'Sheet',1,'Range','B4:B4','OutputType','string');
                    Name_File = py.numpy.str_(Input_file);
                    Name_Output = py.numpy.str_(Output_file);
                    Name_dir = py.numpy.str_(pwd);
                end

                cd(C_location)
                pyrunfile("drta3toNWB.py",Fname = Name_File,Outname = Name_Output,Fdir = Name_dir)
                if app.SingleFileButton.Value == 1
                    cd(app.FSinput11.Value)
                    CurrentDateTime = datetime("now");
                    Time_Full = char(CurrentDateTime);
                    Time_name = append(Time_Full(1:end-9),'_H-',Time_Full(end-7:end-6),'M-',Time_Full(end-4:end-3));
                    New_DIR = [app.FSinput1.Value(1:end-4),Time_name];
                    mkdir(New_DIR)
                    cd(New_DIR)
                    NewLocation = pwd;
                    cd(C_location)
                    movefile('NWB_Settings.xlsx' ,NewLocation)
                    movefile(app.FSinput0.Value,NewLocation)
                    movefile(app.FSinput1.Value,NewLocation)
                else
                    movefile('NWB_Settings.xlsx' ,app.File_table{fm,1}{:,:})
                    movefile(Input_file,app.File_table{fm,1}{:,:})
                    movefile(Output_file,app.File_table{fm,1}{:,:})
                end
            end

        end

        % Button pushed function: ChooseOutputLocationButton
        function ChooseOutputLocationButtonPushed(app, event)
            [Fpath] = uigetdir();
            app.OutputLocation = Fpath;
            app.LocationForOutput.Value = Fpath;

        end

        % Selection changed function: drtaProcessVersion
        function drtaProcessVersionSelectionChanged(app, event)
            selectedButton = app.drtaProcessVersion.SelectedObject;
            switch selectedButton.Text
                case "drta 3.0"
                    app.GridLayoutTraces.Visible = "on";
                    app.GridLayoutSave.Visible = "off";
                case "drta 2.0"
                    app.GridLayoutTraces.Visible = "off";
                    app.GridLayoutSave.Visible = "on";
            end
        end

        % Button pushed function: NextFile
        function NewConverFilePlusButton(app, event)
            app.CreateFileforConversionButton.Enable = "on";
            app.ConvertrhdFileButton.Enable = "off";
            app.LocationForOutput.Enable = "off";
            app.InputFileName.Value = "Select a new file";
            app.OutputFileName.Value = "Select a new file";
            ClearAllConvertFileds(app)
            app.CreateFileforConversionButton.Value = 0;
            app.File_amt = app.File_amt + 1;
            app.CurrentFileNumber.Value = app.File_amt;
            if app.File_totCount > app.File_amt
                C_location = pwd;
                cd(app.File_table{app.File_amt,1}{:,:})
                movefile("NWB_Setting.xlsx",C_location)
                cd(C_location)
                LoadAllConvertFileds(app)
                delete("NWB_Setting.xlsx")
            end

        end

        % Button pushed function: LastFile
        function LastFileRecallButton(app, event)
            
            tempCurrentLocation = pwd;
            tempFolderLocation = app.File_table{app.File_amt,1}{:,:};
            cd(tempFolderLocation);
            movefile("NWB_Settings.xlsx",tempCurrentLocation);
            cd(tempCurrentLocation);
            LoadAllConvertFileds(app)
            app.File_amt = app.File_amt - 1;
            app.CurrentFileNumber.Value = app.CurrentFileNumber.Value - 1;
            app.FileNumber.Value = app.File_amt;
            if app.File_amt == 0
                app.LastFile.Enable = "off";
                app.ConvertrhdFileButton.Enable = "off";
            end

        end

        % Value changed function: CreateFileforConversionButton
        function CreateConversionFileButton(app, event)
%             app.CreateFileforConversionButton.Enable = "off";
            C_location = pwd;
            if app.File_amt == 0
                app.File_amt = app.File_amt + 1;
                
                cd(app.FSinput11.Value)
                CurrentDateTime = datetime("now");
                CurrentDateTime = char(CurrentDateTime);
                NewBatchFile_Name = ['nwbBatchFile_',CurrentDateTime(1:11), ...
                    'H',CurrentDateTime(13:14), ...
                    'M',CurrentDateTime(16:17)];
                mkdir(NewBatchFile_Name);
                cd(NewBatchFile_Name);
                app.File_location = pwd;
                cd(C_location)
                app.FSinput11.Enable = "off";
                app.LocationForOutput.Enable = "off";
            else
%                 app.File_amt = app.File_amt - 1;
                CurrentDateTime = datetime("now");
            end
            cd(app.File_location)
            Time_Full = char(CurrentDateTime);
            NewP_FolderName = [app.FSinput1.Value(1:end-4),'_',Time_Full(1:end-9), ...
                    '_H-',Time_Full(end-7:end-6),'M-',Time_Full(end-4:end-3)];
            mkdir(NewP_FolderName)
            cd(NewP_FolderName)
            app.File_table{app.File_amt,1}{:,:} = pwd;
            cd(app.File_rhdlocation)
            movefile(app.FSinput0.Value,app.File_table{app.File_amt,1}{:,:});
            cd(C_location);
            cd("BlankSheet")
            copyfile("NWB_Settings.xlsx",C_location)
            cd(C_location)
            WritingAllConvertFields(app)
            movefile("NWB_Settings.xlsx",app.File_table{app.File_amt,1}{:,:})
            app.FileNumber.Value = app.File_amt;
            app.ConvertrhdFileButton.Enable = "on";
            if app.File_totCount == app.File_amt-1
                app.File_totCount = app.File_totCount +1;
            end
            app.NextFile.Visible = "on";
%             app.CreateFileforConversionButton.Enable = "on";

        end

        % Value changed function: ChooseFileButton
        function ChooseFileButtonValueChanged(app, event)
            [FileName,PathName] = uigetfile({'*.rhd';'*.dg'},'Select rhd or dg file to open');
            app.File_rhdlocation = PathName;
            app.File_rhdname = FileName;
            app.FSinput0.Value = FileName;
            app.NWBFileName = [FileName(1:end-4),'.nwb'];
            app.FSinput1.Value = app.NWBFileName;
            app.InputFileName.Value = FileName;
            app.OutputFileName.Value = app.NWBFileName;
            app.ConvertrhdFileButton.Enable = "on";
            
        end

        % Value changed function: ChooseLocationButton
        function ChooseLocationButtonValueChanged(app, event)
            [Fpath] = uigetdir();
            app.FSinput11.Value = Fpath;
            app.LocationForOutput.Value = Fpath;
        end

        % Value changed function: SavefileButton
        function SavefileButtonValueChanged(app, event)
            which_c_program = ...
                app.ProtocolTypeDropDown.Value;
            switch which_c_program
                case 'dropcnsampler'
                    app.drta_handles.p.which_c_program = 1;
                case 'dropcspm'
                    app.drta_handles.p.which_c_program = 2;
                case 'background'
                    app.drta_handles.p.which_c_program = 3;
                case 'spmult'
                    app.drta_handles.p.which_c_program = 4;
                case 'mspy'
                    app.drta_handles.p.which_c_program = 5;
                case 'osampler'
                    app.drta_handles.p.which_c_program = 6;
                case 'spm2mult'
                    app.drta_handles.p.which_c_program = 7;
                case 'lighton1'
                    app.drta_handles.p.which_c_program = 8;
                case 'lighton5'
                    app.drta_handles.p.which_c_program = 9;
                case 'dropcspm_conc'
                    app.drta_handles.p.which_c_program = 10;
                case 'Laser-triggered'
                    app.drta_handles.p.which_c_program = 11;
                case 'Laser-Merouann'
                    app.drta_handles.p.which_c_program = 12;
                case 'dropcspm_hf'
                    app.drta_handles.p.which_c_program = 13;
                case 'Working_memory'
                    app.drta_handles.p.which_c_program = 14;
                case 'Continuous'
                    app.drta_handles.p.which_c_program = 15;
            end
            app.drtaHandles = drtaBrowseTraces('updateHandles',app.h,[],app.drta_handles);
            drtaGenerateEvents(app.drtaHandles);
        end

        % Value changed function: CollectdatafrombrowsetracesButton
        function CollectdatafrombrowsetracesButtonValueChanged(app, event)
            app.drta_handles = HandlesSave(hObject, eventdata);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.drta_loadTab = uitab(app.TabGroup);
            app.drta_loadTab.Title = 'drta_load';
            app.GridLayoutLoad = uigridlayout(app.drta_loadTab);
            app.SelectFile_Button = uibutton(app.GridLayoutLoad, 'state');
            app.SelectFile_Button.ValueChangedFcn = createCallbackFcn(app, @SelectFile_ButtonValueChanged, true);
            app.SelectFile_Button.Visible = 'off';
            app.SelectFile_Button.Text = 'Choose File (.rhd or .dg)';
            app.copyright_Label = uilabel(app.GridLayoutLoad);
            app.copyright_Label.Text = 'drta version 3.0 copyright Diego Restrepo';
            app.Load_Button = uibutton(app.GridLayoutLoad, 'state');
            app.Load_Button.ValueChangedFcn = createCallbackFcn(app, @Load_ButtonValueChanged, true);
            app.Load_Button.Visible = 'off';
            app.Load_Button.Text = 'Load File';
            app.ProgressReadout = uitextarea(app.GridLayoutLoad);
            app.type_DropDown_2 = uidropdown(app.GridLayoutLoad);
            app.type_DropDown_2.Items = {'dropcspm', 'laser (Ming)', 'dropcnsampler', 'laser (Merouann)', 'dropc_conc', 'dropcspm_hf', 'continuous', 'working_memory'};
            app.type_DropDown_2.Visible = 'off';
            app.type_DropDown_2.Value = 'dropcspm';
            app.dt_Label = uilabel(app.GridLayoutLoad);
            app.dt_Label.Visible = 'off';
            app.dt_Label.Text = 'dt before odor end (s)';
            app.dt_amt = uieditfield(app.GridLayoutLoad, 'numeric');
            app.dt_amt.Visible = 'off';
            app.dt_amt.Value = 6;
            app.Trial_Label = uilabel(app.GridLayoutLoad);
            app.Trial_Label.Visible = 'off';
            app.Trial_Label.Text = 'Trial duration (s)';
            app.trial_amt = uieditfield(app.GridLayoutLoad, 'numeric');
            app.trial_amt.Visible = 'off';
            app.trial_amt.Value = 9;
            app.file_name = uitextarea(app.GridLayoutLoad);
            app.file_name.Visible = 'off';
            app.file_name.Value = {'No File Selected'};
            app.AppType = uibuttongroup(app.GridLayoutLoad);
            app.AppType.SelectionChangedFcn = createCallbackFcn(app, @AppTypeSelectionChanged, true);
            app.rhdtoNWBButton = uiradiobutton(app.AppType);
            app.rhdtoNWBButton.Text = 'rhd to NWB';
            app.ViewandprocessrhdfileButton = uiradiobutton(app.AppType);
            app.ViewandprocessrhdfileButton.Text = 'View and process rhd file';
            app.ViewandprocessrhdfileButton.Value = true;
            app.drtaProcessVersion = uibuttongroup(app.GridLayoutLoad);
            app.drtaProcessVersion.SelectionChangedFcn = createCallbackFcn(app, @drtaProcessVersionSelectionChanged, true);
            app.drtaProcessVersion.Visible = 'off';
            app.drta20Button = uiradiobutton(app.drtaProcessVersion);
            app.drta20Button.Text = 'drta 2.0';
            app.drta20Button.Value = true;
            app.drta30Button = uiradiobutton(app.drtaProcessVersion);
            app.drta30Button.Text = 'drta 3.0';
            app.NWBconvert = uibuttongroup(app.GridLayoutLoad);
            app.NWBconvert.SelectionChangedFcn = createCallbackFcn(app, @NWBconvertSelectionChanged, true);
            app.NWBconvert.Visible = 'off';
            app.SingleFileButton = uiradiobutton(app.NWBconvert);
            app.SingleFileButton.Text = 'Single File';
            app.SingleFileButton.Value = true;
            app.BatchofFilesButton = uiradiobutton(app.NWBconvert);
            app.BatchofFilesButton.Text = 'Batch of Files';
            app.NumberOfFileLabel = uilabel(app.GridLayoutLoad);
            app.NumberOfFileLabel.Text = 'Number of files needing to be converted';
            app.FileNumber = uieditfield(app.GridLayoutLoad, 'numeric');
            app.FileNumber.Limits = [0 50];
            app.FileNumber.Visible = 'off';
            app.FIlenumberProcessLabel = uilabel(app.GridLayoutLoad);
            app.FIlenumberProcessLabel.Text = 'FIle number beign generated';
            app.LastFile = uibutton(app.GridLayoutLoad, 'push');
            app.LastFile.ButtonPushedFcn = createCallbackFcn(app, @LastFileRecallButton, true);
            app.LastFile.Visible = 'off';
            app.LastFile.Text = '-';
            app.NextFile = uibutton(app.GridLayoutLoad, 'push');
            app.NextFile.ButtonPushedFcn = createCallbackFcn(app, @NewConverFilePlusButton, true);
            app.NextFile.Visible = 'off';
            app.NextFile.Text = '+';
            app.CurrentFileNumber = uieditfield(app.GridLayoutLoad, 'numeric');
            app.CurrentFileNumber.Limits = [1 Inf];
            app.CurrentFileNumber.Visible = 'off';
            app.CurrentFileNumber.Value = 1;
            app.CreateFileforConversionButton = uibutton(app.GridLayoutLoad, 'state');
            app.CreateFileforConversionButton.ValueChangedFcn = createCallbackFcn(app, @CreateConversionFileButton, true);
            app.CreateFileforConversionButton.Visible = 'off';
            app.CreateFileforConversionButton.Text = 'Create File for Conversion';
            app.IntanfilenameLabel = uilabel(app.GridLayoutLoad);
            app.IntanfilenameLabel.Visible = 'off';
            app.IntanfilenameLabel.Text = 'Intan file name';
            app.NWBfilenameLabel = uilabel(app.GridLayoutLoad);
            app.NWBfilenameLabel.Visible = 'off';
            app.NWBfilenameLabel.Text = 'NWB file name';
            app.OutputFileName = uieditfield(app.GridLayoutLoad, 'text');
            app.OutputFileName.Visible = 'off';
            app.ConvertrhdFileButton = uibutton(app.GridLayoutLoad, 'state');
            app.ConvertrhdFileButton.ValueChangedFcn = createCallbackFcn(app, @ConvertrhdFileButtonValueChanged, true);
            app.ConvertrhdFileButton.Visible = 'off';
            app.ConvertrhdFileButton.Text = 'Convert rhd File';
            app.InputFileName = uitextarea(app.GridLayoutLoad);
            app.InputFileName.Visible = 'off';
            app.ChooseOutputLocationButton = uibutton(app.GridLayoutLoad, 'push');
            app.ChooseOutputLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseOutputLocationButtonPushed, true);
            app.ChooseOutputLocationButton.Visible = 'off';
            app.ChooseOutputLocationButton.Text = 'Choose Output Location';
            app.LocationForOutput = uitextarea(app.GridLayoutLoad);
            app.LocationForOutput.Visible = 'off';
            app.OutputLocationLabel = uilabel(app.GridLayoutLoad);
            app.OutputLocationLabel.Visible = 'off';
            app.OutputLocationLabel.Text = 'Output Location';
            app.Browse_TracesTab = uitab(app.TabGroup);
            app.Browse_TracesTab.Title = 'Browse_Traces';
            app.GridLayoutTraces = uigridlayout(app.Browse_TracesTab);
            app.uV_Label = uilabel(app.GridLayoutTraces);
            app.uV_Label.Text = 'uV';
            app.Sub_1 = uidropdown(app.GridLayoutTraces);
            app.Sub_1.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_1.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_1.Value = '01';
            app.Sub_2 = uidropdown(app.GridLayoutTraces);
            app.Sub_2.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_2.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_2.Value = '05';
            app.Sub_3 = uidropdown(app.GridLayoutTraces);
            app.Sub_3.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_3.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_3.Value = '02';
            app.Sub_4 = uidropdown(app.GridLayoutTraces);
            app.Sub_4.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_4.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_4.Value = '03';
            app.Sub_5 = uidropdown(app.GridLayoutTraces);
            app.Sub_5.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_5.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_5.Value = '04';
            app.Sub_6 = uidropdown(app.GridLayoutTraces);
            app.Sub_6.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_6.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_6.Value = '06';
            app.Sub_7 = uidropdown(app.GridLayoutTraces);
            app.Sub_7.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_7.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_7.Value = '07';
            app.Sub_8 = uidropdown(app.GridLayoutTraces);
            app.Sub_8.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_8.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_8.Value = '08';
            app.Sub_9 = uidropdown(app.GridLayoutTraces);
            app.Sub_9.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_9.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_9.Value = '09';
            app.Sub_10 = uidropdown(app.GridLayoutTraces);
            app.Sub_10.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_10.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_10.Value = '10';
            app.Sub_11 = uidropdown(app.GridLayoutTraces);
            app.Sub_11.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_11.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_11.Value = '11';
            app.Sub_12 = uidropdown(app.GridLayoutTraces);
            app.Sub_12.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_12.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_12.Value = '12';
            app.Sub_13 = uidropdown(app.GridLayoutTraces);
            app.Sub_13.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_13.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_13.Value = '13';
            app.Sub_14 = uidropdown(app.GridLayoutTraces);
            app.Sub_14.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_14.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_14.Value = '14';
            app.Sub_15 = uidropdown(app.GridLayoutTraces);
            app.Sub_15.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_15.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_15.Value = '15';
            app.Sub_16 = uidropdown(app.GridLayoutTraces);
            app.Sub_16.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_16.ValueChangedFcn = createCallbackFcn(app, @SubValueChanged, true);
            app.Sub_16.Value = '16';
            app.Yrange_Label = uilabel(app.GridLayoutTraces);
            app.Yrange_Label.Text = 'Y Range (uV):';
            app.Yrange_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Yrange_amt.ValueChangedFcn = createCallbackFcn(app, @Yrange_amtValueChanged, true);
            app.Yrange_amt.Value = 4000;
            app.Tnum_Larrow = uibutton(app.GridLayoutTraces, 'push');
            app.Tnum_Larrow.ButtonPushedFcn = createCallbackFcn(app, @Tnum_LarrowButtonPushed, true);
            app.Tnum_Larrow.Text = '<--';
            app.Tnum_Rarrow = uibutton(app.GridLayoutTraces, 'push');
            app.Tnum_Rarrow.ButtonPushedFcn = createCallbackFcn(app, @Tnum_RarrowButtonPushed, true);
            app.Tnum_Rarrow.Text = '-->';
            app.Tnum_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Tnum_amt.ValueChangedFcn = createCallbackFcn(app, @Tnum_amtValueChanged, true);
            app.Tnum_amt.Value = 1;
            app.Interval_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Interval_amt.ValueChangedFcn = createCallbackFcn(app, @Interval_amtValueChanged, true);
            app.Interval_amt.Value = 1;
            app.Interval_Label = uilabel(app.GridLayoutTraces);
            app.Interval_Label.Text = 'Interval (sec):';
            app.DTS_Label = uilabel(app.GridLayoutTraces);
            app.DTS_Label.Text = 'Display Time Start (sec):';
            app.Tnum_Label = uilabel(app.GridLayoutTraces);
            app.Tnum_Label.Text = 'Trial No:';
            app.DTS_Rarrow = uibutton(app.GridLayoutTraces, 'push');
            app.DTS_Rarrow.ButtonPushedFcn = createCallbackFcn(app, @DTS_RarrowButtonPushed, true);
            app.DTS_Rarrow.Text = '-->';
            app.DTS_Larrow = uibutton(app.GridLayoutTraces, 'push');
            app.DTS_Larrow.ButtonPushedFcn = createCallbackFcn(app, @DTS_LarrowButtonPushed, true);
            app.DTS_Larrow.Text = '<--';
            app.Disp_Label = uilabel(app.GridLayoutTraces);
            app.Disp_Label.Text = 'Display:';
            app.Choice_DropDown = uidropdown(app.GridLayoutTraces);
            app.Choice_DropDown.Items = {'Raw', 'Wide 4-100', 'High Theta 6-14', 'Theta 2-14', 'Beta 15-36', 'Gamma1 35-65', 'Gamma2 65-95', 'Gamma 35-95', 'Spikes 500-5000', 'Spike var digital'};
            app.Choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @Choice_DropDownValueChanged, true);
            app.Choice_DropDown.Value = 'Raw';
            app.Disp_DropDown = uidropdown(app.GridLayoutTraces);
            app.Disp_DropDown.Items = {'all', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Digital'};
            app.Disp_DropDown.ValueChangedFcn = createCallbackFcn(app, @Disp_DropDownValueChanged, true);
            app.Disp_DropDown.Value = 'all';
            app.Yrange_DropDown = uidropdown(app.GridLayoutTraces);
            app.Yrange_DropDown.Items = {'all', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Digital'};
            app.Yrange_DropDown.ValueChangedFcn = createCallbackFcn(app, @Yrange_DropDownValueChanged, true);
            app.Yrange_DropDown.Value = 'all';
            app.Licks_CheckBox = uicheckbox(app.GridLayoutTraces);
            app.Licks_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Licks_CheckBoxValueChanged, true);
            app.Licks_CheckBox.Text = 'Exclude Licks';
            app.MClust_Button = uibutton(app.GridLayoutTraces, 'state');
            app.MClust_Button.ValueChangedFcn = createCallbackFcn(app, @MClust_ButtonValueChanged, true);
            app.MClust_Button.Text = 'MClust';
            app.Diff_CheckBox = uicheckbox(app.GridLayoutTraces);
            app.Diff_CheckBox.Text = 'Differential';
            app.min_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.min_amt.ValueChangedFcn = createCallbackFcn(app, @min_amtValueChanged, true);
            app.min_amt.Value = 50;
            app.min_Label = uilabel(app.GridLayoutTraces);
            app.min_Label.Text = 'LFP min';
            app.max_Label = uilabel(app.GridLayoutTraces);
            app.max_Label.Text = 'LFP max';
            app.max_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.max_amt.ValueChangedFcn = createCallbackFcn(app, @max_amtValueChanged, true);
            app.max_amt.Value = 3900;
            app.nxSD_Label = uilabel(app.GridLayoutTraces);
            app.nxSD_Label.Text = 'Set nxSD';
            app.nxSD_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.nxSD_amt.ValueChangedFcn = createCallbackFcn(app, @nxSD_amtValueChanged, true);
            app.nxSD_amt.Value = 2.5;
            app.thr_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.thr_amt.ValueChangedFcn = createCallbackFcn(app, @thr_amtValueChanged, true);
            app.thr_Label = uilabel(app.GridLayoutTraces);
            app.thr_Label.Text = 'Set thr (uv)';
            app.DTS_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.DTS_amt.ValueChangedFcn = createCallbackFcn(app, @DTS_amtValueChanged, true);
            app.type_DropDown = uidropdown(app.GridLayoutTraces);
            app.type_DropDown.Items = {'dropcspm', 'dropcnsampler', 'background', 'spmult', 'mspy', 'osampler', 'spm2mult', 'lighton1', 'lighton5', 'dropcspm_conc', 'Laser-triggered', 'Laser-Merouann', 'dropcspm_hf', 'Working_memory', 'Continuous'};
            app.type_DropDown.ValueChangedFcn = createCallbackFcn(app, @type_DropDownValueChanged, true);
            app.type_DropDown.Value = 'dropcspm';
            app.NWB_Label = uilabel(app.GridLayoutTraces);
            app.NWB_Label.Text = 'NWB format';
            app.SaveButton = uibutton(app.GridLayoutTraces, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.NWB_CheckBox = uicheckbox(app.GridLayoutTraces);
            app.NWB_CheckBox.Text = '';
            app.FileSettingsTab = uitab(app.TabGroup);
            app.FileSettingsTab.Title = 'File Settings';
            app.GridLayout = uigridlayout(app.FileSettingsTab);
            app.IntanfilenameLabel_2 = uilabel(app.GridLayout);
            app.IntanfilenameLabel_2.Text = 'Intan filename';
            app.NWBfilenameLabel_2 = uilabel(app.GridLayout);
            app.NWBfilenameLabel_2.Text = 'NWB filename';
            app.SessiondescriptionNote1Note2Label = uilabel(app.GridLayout);
            app.SessiondescriptionNote1Note2Label.Text = 'Session description [Note1 + Note2 ...]';
            app.Blocksperchunk1000Label = uilabel(app.GridLayout);
            app.Blocksperchunk1000Label.Text = 'Blocks per chunk [1000]';
            app.UseCompressionLabel = uilabel(app.GridLayout);
            app.UseCompressionLabel.Text = 'Use Compression';
            app.CompressionLevelLabel = uilabel(app.GridLayout);
            app.CompressionLevelLabel.Text = 'Compression Level';
            app.LowpassdescriptionLabel = uilabel(app.GridLayout);
            app.LowpassdescriptionLabel.Text = 'Lowpass description';
            app.HighpassdescriptionLabel = uilabel(app.GridLayout);
            app.HighpassdescriptionLabel.Text = 'Highpass description';
            app.MergefilesLabel = uilabel(app.GridLayout);
            app.MergefilesLabel.Text = 'Merge files';
            app.IncludetimesettingsLabel = uilabel(app.GridLayout);
            app.IncludetimesettingsLabel.Text = 'Include time settings';
            app.IncludesubjectmetadataLabel = uilabel(app.GridLayout);
            app.IncludesubjectmetadataLabel.Text = 'Include subject metadata';
            app.FSinput0 = uieditfield(app.GridLayout, 'text');
            app.FSinput1 = uieditfield(app.GridLayout, 'text');
            app.FSinput2 = uieditfield(app.GridLayout, 'text');
            app.FSinput3 = uieditfield(app.GridLayout, 'text');
            app.FSinput3.Value = '1000';
            app.FSinput5 = uieditfield(app.GridLayout, 'text');
            app.FSinput5.Value = '5';
            app.FSinput6 = uieditfield(app.GridLayout, 'text');
            app.FSinput7 = uieditfield(app.GridLayout, 'text');
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.FSinput4yes = uiradiobutton(app.ButtonGroup);
            app.FSinput4yes.Text = 'Yes';
            app.FSinput4yes.Value = true;
            app.FSinput4no = uiradiobutton(app.ButtonGroup);
            app.FSinput4no.Text = 'No';
            app.ButtonGroup_2 = uibuttongroup(app.GridLayout);
            app.FSinput8yes = uiradiobutton(app.ButtonGroup_2);
            app.FSinput8yes.Text = 'Yes';
            app.FSinput8no = uiradiobutton(app.ButtonGroup_2);
            app.FSinput8no.Text = 'No';
            app.FSinput8no.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.GridLayout);
            app.FSinput9yes = uiradiobutton(app.ButtonGroup_3);
            app.FSinput9yes.Text = 'Yes';
            app.FSinput9no = uiradiobutton(app.ButtonGroup_3);
            app.FSinput9no.Text = 'No';
            app.FSinput9no.Value = true;
            app.ButtonGroup_4 = uibuttongroup(app.GridLayout);
            app.FSinput10yes = uiradiobutton(app.ButtonGroup_4);
            app.FSinput10yes.Text = 'Yes';
            app.FSinput10no = uiradiobutton(app.ButtonGroup_4);
            app.FSinput10no.Text = 'No';
            app.FSinput10no.Value = true;
            app.ChooseFileButton = uibutton(app.GridLayout, 'state');
            app.ChooseFileButton.ValueChangedFcn = createCallbackFcn(app, @ChooseFileButtonValueChanged, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.OutputlocationLabel = uilabel(app.GridLayout);
            app.OutputlocationLabel.Text = 'Output location';
            app.ChooseLocationButton = uibutton(app.GridLayout, 'state');
            app.ChooseLocationButton.ValueChangedFcn = createCallbackFcn(app, @ChooseLocationButtonValueChanged, true);
            app.ChooseLocationButton.Text = 'Choose Location';
            app.FSinput11 = uieditfield(app.GridLayout, 'text');
            app.SessionStartTimeSettingsTab = uitab(app.TabGroup);
            app.SessionStartTimeSettingsTab.Title = 'Session Start Time Settings';
            app.GridLayout2 = uigridlayout(app.SessionStartTimeSettingsTab);
            app.Manualsessionstartyear2019Label = uilabel(app.GridLayout2);
            app.Manualsessionstartyear2019Label.Text = 'Manual session start year [2019]';
            app.Manualsessionstartmonth1Label = uilabel(app.GridLayout2);
            app.Manualsessionstartmonth1Label.Text = 'Manual session start month [1]';
            app.manualsessionstartday17Label = uilabel(app.GridLayout2);
            app.manualsessionstartday17Label.Text = 'manual session start day [17]';
            app.Manualsessionstarthour16Label = uilabel(app.GridLayout2);
            app.Manualsessionstarthour16Label.Text = 'Manual session start hour [16]';
            app.Manualsessionstartminute23Label = uilabel(app.GridLayout2);
            app.Manualsessionstartminute23Label.Text = 'Manual session start minute [23]';
            app.Manualsessionstartsecond43Label = uilabel(app.GridLayout2);
            app.Manualsessionstartsecond43Label.Text = 'Manual session start second [43]';
            app.TSinput0 = uieditfield(app.GridLayout2, 'text');
            app.TSinput1 = uieditfield(app.GridLayout2, 'text');
            app.TSinput2 = uieditfield(app.GridLayout2, 'text');
            app.TSinput3 = uieditfield(app.GridLayout2, 'text');
            app.TSinput4 = uieditfield(app.GridLayout2, 'text');
            app.TSinput5 = uieditfield(app.GridLayout2, 'text');
            app.SubjectMetadataSettingsTab = uitab(app.TabGroup);
            app.SubjectMetadataSettingsTab.Title = 'Subject Metadata Settings';
            app.GridLayout3 = uigridlayout(app.SubjectMetadataSettingsTab);
            app.SubjectageLabel = uilabel(app.GridLayout3);
            app.SubjectageLabel.Text = 'Subject age';
            app.SubjectdescriptionLabel = uilabel(app.GridLayout3);
            app.SubjectdescriptionLabel.Text = 'Subject description';
            app.SubjectgenotypeLabel = uilabel(app.GridLayout3);
            app.SubjectgenotypeLabel.Text = 'Subject genotype';
            app.SubjectsexLabel = uilabel(app.GridLayout3);
            app.SubjectsexLabel.Text = 'Subject sex';
            app.SubjectspeciesLabel = uilabel(app.GridLayout3);
            app.SubjectspeciesLabel.Text = 'Subject species';
            app.SubjectIDLabel = uilabel(app.GridLayout3);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.SubjectweightkgLabel = uilabel(app.GridLayout3);
            app.SubjectweightkgLabel.Text = 'Subject weight (kg)';
            app.SubjectstrainLabel = uilabel(app.GridLayout3);
            app.SubjectstrainLabel.Text = 'Subject strain';
            app.IncludesubjectDOBLabel = uilabel(app.GridLayout3);
            app.IncludesubjectDOBLabel.Text = 'Include subject DOB';
            app.SubjectDOByear2009Label = uilabel(app.GridLayout3);
            app.SubjectDOByear2009Label.Text = 'Subject DOB year [2009]';
            app.SubjectDOBmonth9Label = uilabel(app.GridLayout3);
            app.SubjectDOBmonth9Label.Text = 'Subject DOB month [9]';
            app.SubjectDOBday21Label = uilabel(app.GridLayout3);
            app.SubjectDOBday21Label.Text = 'Subject DOB day [21]';
            app.MSinput0 = uieditfield(app.GridLayout3, 'text');
            app.MSinput1 = uieditfield(app.GridLayout3, 'text');
            app.MSinput2 = uieditfield(app.GridLayout3, 'text');
            app.MSinput3 = uieditfield(app.GridLayout3, 'text');
            app.MSinput4 = uieditfield(app.GridLayout3, 'text');
            app.MSinput5 = uieditfield(app.GridLayout3, 'text');
            app.MSinput6 = uieditfield(app.GridLayout3, 'text');
            app.MSinput7 = uieditfield(app.GridLayout3, 'text');
            app.ButtonGroup_5 = uibuttongroup(app.GridLayout3);
            app.MSinput8yes = uiradiobutton(app.ButtonGroup_5);
            app.MSinput8yes.Text = 'Yes';
            app.MSinput8yes.Value = true;
            app.MSinput8no = uiradiobutton(app.ButtonGroup_5);
            app.MSinput8no.Text = 'No';
            app.MSinput9 = uieditfield(app.GridLayout3, 'text');
            app.MSinput10 = uieditfield(app.GridLayout3, 'text');
            app.MSinput11 = uieditfield(app.GridLayout3, 'text');
            app.Save_fileTab = uitab(app.TabGroup);
            app.Save_fileTab.Title = 'Save_file';
            app.GridLayoutSave = uigridlayout(app.Save_fileTab);
            app.CollectdatafrombrowsetracesButton = uibutton(app.GridLayoutSave, 'state');
            app.CollectdatafrombrowsetracesButton.ValueChangedFcn = createCallbackFcn(app, @CollectdatafrombrowsetracesButtonValueChanged, true);
            app.CollectdatafrombrowsetracesButton.Text = 'Collect data from browse traces';
            app.ProtocolTypeDropDownLabel = uilabel(app.GridLayoutSave);
            app.ProtocolTypeDropDownLabel.Text = 'Protocol Type';
            app.ProtocolTypeDropDown = uidropdown(app.GridLayoutSave);
            app.ProtocolTypeDropDown.Items = {'dropcspm', 'dropcnsampler', 'background', 'spmult', 'mspy', 'osampler', 'spm2mult', 'lighton1', 'lighton5', 'dropcspm_conc', 'Laser-triggered', 'Laser-Merouann', 'dropcspm_hf', 'Working_memory', 'Continuous'};
            app.ProtocolTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @type_DropDownValueChanged, true);
            app.ProtocolTypeDropDown.Value = 'dropcspm';
            app.SavefileButton = uibutton(app.GridLayoutSave, 'state');
            app.SavefileButton.ValueChangedFcn = createCallbackFcn(app, @SavefileButtonValueChanged, true);
            app.SavefileButton.Text = 'Save file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drtaNWB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end